classdef StrutGen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        StrutandPlatetypeLatticeGeneratorTab  matlab.ui.container.Tab
        Panel_13                     matlab.ui.container.Panel
        SummaryLabel_3               matlab.ui.control.Label
        exactRelativeDensity         matlab.ui.control.EditField
        ExactRelativeDensityLabel_7  matlab.ui.control.Label
        Runtime                      matlab.ui.control.EditField
        ExactRelativeDensityLabel_6  matlab.ui.control.Label
        Nodes                        matlab.ui.control.EditField
        ExactRelativeDensityLabel_5  matlab.ui.control.Label
        LatticeVolume                matlab.ui.control.EditField
        ExactRelativeDensityLabel_4  matlab.ui.control.Label
        Panel_12                     matlab.ui.container.Panel
        ResolutionControlLabel       matlab.ui.control.Label
        Curvepoints                  matlab.ui.control.Spinner
        CurvepointsLabel             matlab.ui.control.Label
        Gridpoints                   matlab.ui.control.Spinner
        NumberofGridpointsSpinnerLabel_4  matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        ThicknessLabel_7             matlab.ui.control.Label
        curve_control_2              matlab.ui.control.Spinner
        radius_parameter             matlab.ui.control.Spinner
        ZLabel_21                    matlab.ui.control.Label
        SaveCustom                   matlab.ui.control.Button
        OuterDiameter2               matlab.ui.control.Spinner
        InnerDiameter2               matlab.ui.control.Spinner
        ThicknessLabel_6             matlab.ui.control.Label
        OuterDiameter                matlab.ui.control.Spinner
        InnerDiameter                matlab.ui.control.Spinner
        ThicknessLabel_5             matlab.ui.control.Label
        DensityControl               matlab.ui.control.Switch
        coefficientD_grade           matlab.ui.control.Spinner
        ZLabel_19                    matlab.ui.control.Label
        DensityType                  matlab.ui.control.Switch
        positionZ_grade              matlab.ui.control.Spinner
        ZLabel_18                    matlab.ui.control.Label
        positionY_grade              matlab.ui.control.Spinner
        YLabel_16                    matlab.ui.control.Label
        positionX_grade              matlab.ui.control.Spinner
        ALabel_6                     matlab.ui.control.Label
        coefficientC_grade           matlab.ui.control.Spinner
        ZLabel_17                    matlab.ui.control.Label
        coefficientB_grade           matlab.ui.control.Spinner
        YLabel_15                    matlab.ui.control.Label
        coefficientA_grade           matlab.ui.control.Spinner
        ALabel_5                     matlab.ui.control.Label
        relativeDensity_grade_2      matlab.ui.control.Spinner
        RelativeDensity2Label        matlab.ui.control.Label
        thickness_grade_2            matlab.ui.control.Spinner
        ThicknessLabel_4             matlab.ui.control.Label
        ThicknessLabel_3             matlab.ui.control.Label
        curve_control                matlab.ui.control.Spinner
        RelativePosition_grade       matlab.ui.control.Label
        CoefficientLabel             matlab.ui.control.Label
        GradingFormula               matlab.ui.control.EditField
        GradingEquation              matlab.ui.control.DropDown
        EquationLabel                matlab.ui.control.Label
        Stop                         matlab.ui.control.StateButton
        LampLabel                    matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        ExportSTL                    matlab.ui.control.Button
        Plot                         matlab.ui.control.StateButton
        relativeDensity              matlab.ui.control.Spinner
        CellRelativeDensityLabel     matlab.ui.control.Label
        thickness                    matlab.ui.control.Spinner
        ThicknessLabel               matlab.ui.control.Label
        DensityControlLabel          matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        CustomBrowseButton           matlab.ui.control.StateButton
        ImportCustomCell             matlab.ui.control.EditField
        Hollow                       matlab.ui.control.Switch
        RemoveStrut                  matlab.ui.control.StateButton
        NumberOfStruts               matlab.ui.control.EditField
        Topology2                    matlab.ui.control.DropDown
        CellTopology2Label           matlab.ui.control.Label
        HybridStructure              matlab.ui.control.Switch
        AddStrut                     matlab.ui.control.StateButton
        EndPointZ                    matlab.ui.control.Spinner
        ZLabel_16                    matlab.ui.control.Label
        EndPointY                    matlab.ui.control.Spinner
        YLabel_14                    matlab.ui.control.Label
        EndPointX                    matlab.ui.control.Spinner
        XSpinnerLabel_11             matlab.ui.control.Label
        StartPointZ                  matlab.ui.control.Spinner
        ZLabel_15                    matlab.ui.control.Label
        StartPointY                  matlab.ui.control.Spinner
        YLabel_13                    matlab.ui.control.Label
        EndPointmmLabel              matlab.ui.control.Label
        StartPointmmLabel            matlab.ui.control.Label
        StartPointX                  matlab.ui.control.Spinner
        XSpinnerLabel_10             matlab.ui.control.Label
        CustomStrutCellsLabel        matlab.ui.control.Label
        Topology                     matlab.ui.control.DropDown
        FunctionLabel                matlab.ui.control.Label
        UnitCellDesignLabel          matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        height                       matlab.ui.control.Spinner
        ZLabel_20                    matlab.ui.control.Label
        CompressiveSample            matlab.ui.control.DropDown
        ImportCustomDomainLabel_2    matlab.ui.control.Label
        cellZ                        matlab.ui.control.Spinner
        ZLabel_2                     matlab.ui.control.Label
        cellY                        matlab.ui.control.Spinner
        YLabel_2                     matlab.ui.control.Label
        cellX                        matlab.ui.control.Spinner
        XSpinnerLabel_5              matlab.ui.control.Label
        cuboidZ                      matlab.ui.control.Spinner
        CellDimmmLabel               matlab.ui.control.Label
        ZLabel                       matlab.ui.control.Label
        cuboidY                      matlab.ui.control.Spinner
        YLabel                       matlab.ui.control.Label
        cuboidX                      matlab.ui.control.Spinner
        XSpinnerLabel_4              matlab.ui.control.Label
        CuboidDimmmLabel             matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.StateButton
        fileName                     matlab.ui.control.EditField
        NameofFileLabel              matlab.ui.control.Label
        ImportedMesh                 matlab.ui.control.DropDown
        ImportCustomDomainLabel      matlab.ui.control.Label
        GeometryInitializationLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        HomogenizationTab            matlab.ui.container.Tab
        Panel_20                     matlab.ui.container.Panel
        ALabel_20                    matlab.ui.control.Label
        ALabel_19                    matlab.ui.control.Label
        ALabel_18                    matlab.ui.control.Label
        ALabel_17                    matlab.ui.control.Label
        ALabel_16                    matlab.ui.control.Label
        ALabel_15                    matlab.ui.control.Label
        ALabel_14                    matlab.ui.control.Label
        ALabel_13                    matlab.ui.control.Label
        ALabel_12                    matlab.ui.control.Label
        ALabel_11                    matlab.ui.control.Label
        ALabel_10                    matlab.ui.control.Label
        ALabel_9                     matlab.ui.control.Label
        PoissonsRatioLabel           matlab.ui.control.Label
        PR1                          matlab.ui.control.EditField
        PR2                          matlab.ui.control.EditField
        PR3                          matlab.ui.control.EditField
        PR4                          matlab.ui.control.EditField
        PR5                          matlab.ui.control.EditField
        PR6                          matlab.ui.control.EditField
        ResultsLabel                 matlab.ui.control.Label
        YoungsModulusLabel           matlab.ui.control.Label
        YM1                          matlab.ui.control.EditField
        YM2                          matlab.ui.control.EditField
        YM3                          matlab.ui.control.EditField
        ExactRelativeDensityLabel_8  matlab.ui.control.Label
        SM1                          matlab.ui.control.EditField
        SM2                          matlab.ui.control.EditField
        SM3                          matlab.ui.control.EditField
        Panel_19                     matlab.ui.container.Panel
        Poisson                      matlab.ui.control.Spinner
        PoissonLabel                 matlab.ui.control.Label
        Modulus                      matlab.ui.control.Spinner
        ModulusLabel                 matlab.ui.control.Label
        MaterialLabel                matlab.ui.control.Label
        Panel_17                     matlab.ui.container.Panel
        ResolutionControlLabel_2     matlab.ui.control.Label
        Curvepoints_2                matlab.ui.control.Spinner
        CurvepointsLabel_2           matlab.ui.control.Label
        Gridpoints_2                 matlab.ui.control.Spinner
        NumberofGridpointsSpinnerLabel_5  matlab.ui.control.Label
        Panel_16                     matlab.ui.container.Panel
        ThicknessLabel_13            matlab.ui.control.Label
        curve_control_4              matlab.ui.control.Spinner
        radius_parameter_2           matlab.ui.control.Spinner
        ZLabel_30                    matlab.ui.control.Label
        OuterDiameter2_2             matlab.ui.control.Spinner
        InnerDiameter2_2             matlab.ui.control.Spinner
        ThicknessLabel_12            matlab.ui.control.Label
        OuterDiameter_2              matlab.ui.control.Spinner
        InnerDiameter_2              matlab.ui.control.Spinner
        ThicknessLabel_11            matlab.ui.control.Label
        DensityControl_2             matlab.ui.control.Switch
        coefficientD_grade_2         matlab.ui.control.Spinner
        ZLabel_29                    matlab.ui.control.Label
        DensityType_2                matlab.ui.control.Switch
        positionZ_grade_2            matlab.ui.control.Spinner
        ZLabel_28                    matlab.ui.control.Label
        positionY_grade_2            matlab.ui.control.Spinner
        YLabel_22                    matlab.ui.control.Label
        positionX_grade_2            matlab.ui.control.Spinner
        ALabel_8                     matlab.ui.control.Label
        coefficientC_grade_2         matlab.ui.control.Spinner
        ZLabel_27                    matlab.ui.control.Label
        coefficientB_grade_2         matlab.ui.control.Spinner
        YLabel_21                    matlab.ui.control.Label
        coefficientA_grade_2         matlab.ui.control.Spinner
        ALabel_7                     matlab.ui.control.Label
        relativeDensity_grade_3      matlab.ui.control.Spinner
        RelativeDensity2Label_2      matlab.ui.control.Label
        thickness_grade_3            matlab.ui.control.Spinner
        ThicknessLabel_10            matlab.ui.control.Label
        ThicknessLabel_9             matlab.ui.control.Label
        curve_control_3              matlab.ui.control.Spinner
        RelativePosition_grade_2     matlab.ui.control.Label
        CoefficientLabel_2           matlab.ui.control.Label
        GradingFormula_2             matlab.ui.control.EditField
        GradingEquation_2            matlab.ui.control.DropDown
        EquationLabel_2              matlab.ui.control.Label
        Stop_2                       matlab.ui.control.StateButton
        LampLabel_2                  matlab.ui.control.Label
        Lamp_2                       matlab.ui.control.Lamp
        ExportProperties             matlab.ui.control.Button
        RunHomogenization            matlab.ui.control.StateButton
        relativeDensity_2            matlab.ui.control.Spinner
        CellRelativeDensityLabel_2   matlab.ui.control.Label
        thickness_2                  matlab.ui.control.Spinner
        ThicknessLabel_8             matlab.ui.control.Label
        DensityControlLabel_2        matlab.ui.control.Label
        Panel_15                     matlab.ui.container.Panel
        cellZ_2                      matlab.ui.control.Spinner
        ZLabel_31                    matlab.ui.control.Label
        cellY_2                      matlab.ui.control.Spinner
        YLabel_23                    matlab.ui.control.Label
        cellX_2                      matlab.ui.control.Spinner
        XSpinnerLabel_16             matlab.ui.control.Label
        CellDimmmLabel_2             matlab.ui.control.Label
        CustomBrowseButton_2         matlab.ui.control.StateButton
        ImportCustomCell_2           matlab.ui.control.EditField
        Hollow_2                     matlab.ui.control.Switch
        RemoveStrut_2                matlab.ui.control.StateButton
        NumberOfStruts_2             matlab.ui.control.EditField
        Topology2_2                  matlab.ui.control.DropDown
        CellTopology2Label_2         matlab.ui.control.Label
        HybridStructure_2            matlab.ui.control.Switch
        AddStrut_2                   matlab.ui.control.StateButton
        EndPointZ_2                  matlab.ui.control.Spinner
        ZLabel_26                    matlab.ui.control.Label
        EndPointY_2                  matlab.ui.control.Spinner
        YLabel_20                    matlab.ui.control.Label
        EndPointX_2                  matlab.ui.control.Spinner
        XSpinnerLabel_15             matlab.ui.control.Label
        StartPointZ_2                matlab.ui.control.Spinner
        ZLabel_25                    matlab.ui.control.Label
        StartPointY_2                matlab.ui.control.Spinner
        YLabel_19                    matlab.ui.control.Label
        EndPointmmLabel_2            matlab.ui.control.Label
        StartPointmmLabel_2          matlab.ui.control.Label
        StartPointX_2                matlab.ui.control.Spinner
        XSpinnerLabel_14             matlab.ui.control.Label
        CustomStrutCellsLabel_2      matlab.ui.control.Label
        Topology_2                   matlab.ui.control.DropDown
        FunctionLabel_2              matlab.ui.control.Label
        UnitCellDesignLabel_2        matlab.ui.control.Label
        Panel_18                     matlab.ui.container.Panel
        CM1                          matlab.ui.control.EditField
        CM2                          matlab.ui.control.EditField
        CM3                          matlab.ui.control.EditField
        CM4                          matlab.ui.control.EditField
        CM5                          matlab.ui.control.EditField
        CM6                          matlab.ui.control.EditField
        CM7                          matlab.ui.control.EditField
        CM8                          matlab.ui.control.EditField
        CM9                          matlab.ui.control.EditField
        CM10                         matlab.ui.control.EditField
        CM11                         matlab.ui.control.EditField
        CM12                         matlab.ui.control.EditField
        CM13                         matlab.ui.control.EditField
        CM14                         matlab.ui.control.EditField
        CM15                         matlab.ui.control.EditField
        CM16                         matlab.ui.control.EditField
        CM17                         matlab.ui.control.EditField
        CM18                         matlab.ui.control.EditField
        CM19                         matlab.ui.control.EditField
        CM20                         matlab.ui.control.EditField
        CM21                         matlab.ui.control.EditField
        CM22                         matlab.ui.control.EditField
        CM23                         matlab.ui.control.EditField
        CM24                         matlab.ui.control.EditField
        CM25                         matlab.ui.control.EditField
        CM26                         matlab.ui.control.EditField
        CM27                         matlab.ui.control.EditField
        CM28                         matlab.ui.control.EditField
        CM29                         matlab.ui.control.EditField
        CM30                         matlab.ui.control.EditField
        CM36                         matlab.ui.control.EditField
        CM35                         matlab.ui.control.EditField
        CM34                         matlab.ui.control.EditField
        CM33                         matlab.ui.control.EditField
        CM32                         matlab.ui.control.EditField
        CM31                         matlab.ui.control.EditField
        ConstitutiveMatrixLabel      matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
    end


    % Author: Alex Inoma, Osezua Ibhadode
    % e-mail: inoma@ualberta.ca
    % Release: 1.0
    % Release date: 13/01/2025

    properties (Access = public)
        stopFlag = 0; %stop button flag
    end
    
    properties (Access = private)
        customStart = []; % Custom Struts Start Point
        customEnd = []; % Custom Struts End Point
        Faces = 0; % Faces
        Vertices = 0; % Vertices
        ConMatrix = [];
        YModulus = [];
        SModulus = [];
        PRatio = [];
    end
    
    
    methods (Access = private)
            
            function errorFunction(app,message)
                    logFolderPath = fullfile(pwd, 'error_log');
                    if ~exist(logFolderPath, 'dir') % Check if the folder exists
                        mkdir(logFolderPath); % Create the folder if it doesn't exist
                    end
                    logFilePath = fullfile(logFolderPath, 'logfile.txt');
                    
                    if ~exist('error_log',"file")
                        fid = fopen(logFilePath, 'w'); % Open the log file in write mode
                        
                        if fid == -1
                            error('Failed to initialize log file.');
                        end
                        fprintf(fid, 'Log initialized on %s\n', datetime('now')); % Write initialization message
                        fclose(fid); % Close the file
                    else
                        % Open the log file in append mode
                        fid = fopen(logFilePath, 'a');
                        if fid == -1
                            warning('Failed to write to log file.');
                        else
                            fprintf(fid, '[%s] %s\n', datetime('now'), message); % Write the log
                            fclose(fid); % Close the file
                        end
                    end
            end
        
            function RotationCallback(app,c)
                c = camlight(c,'headlight');
            end
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';

            set(app.UIAxes.XLabel,'Color','black'); set(app.UIAxes.YLabel,'Color','black'); set(app.UIAxes.ZLabel,'Color','black');
            

        end

        % Value changed function: ImportedMesh
        function ImportedMeshValueChanged(app, event)
            if strcmp(app.ImportedMesh.Value, "Yes")
                app.fileName.Editable = 'on';
                app.BrowseButton.Enable = 'on';
                app.cuboidX.Enable = 'off';
                app.cuboidY.Enable = 'off';
                app.cuboidZ.Enable = 'off';
                app.CompressiveSample.Enable = 'off';
                app.CompressiveSample.Value = 'No';
                app.height.Enable = 'off';
            else
                app.fileName.Editable = 'off';
                app.BrowseButton.Enable = 'off';
                app.cuboidX.Enable = 'on';
                app.cuboidY.Enable = 'on';
                app.cuboidZ.Enable = 'on';
                app.CompressiveSample.Enable = 'on';
                app.height.Enable = 'on';
            end
        end

        % Value changed function: BrowseButton
        function BrowseButtonValueChanged(app, event)
            [filename,path] = uigetfile('*.stl');
            file = fullfile(path,filename);
            if ~isequal(filename, 0)  % Check if the user canceled the dialog
                app.fileName.Value = file;
            end
            
            app.stopFlag = 0;
                app.Lamp.Color = [1,1,0];
                app.LampLabel.Text = "Running...";
                drawnow;
            
            if contains(file,'.stl') || contains(file,'.STL')
                inp = file;
            else
                inp= strcat(file, '.stl');
            end
           nd = stlread(inp);                                                              % read the imported mesh domain
           ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
           
           %% Plotting Surfaces
            drawnow;
            if app.stopFlag == 0
                trisurf(ndf,ndv(:,1),ndv(:,2),ndv(:,3), 'Facecolor', '[1, 0.341, 0.200]','Edgecolor','none','Parent',app.UIAxes);
                axis(app.UIAxes, "equal");
                c = camlight(app.UIAxes, 'headlight');
                light(app.UIAxes,'position',[0 -1 0]);
                lighting(app.UIAxes,'flat') ;
                pbaspect(app.UIAxes,[1,1,1])
                set(c,'style','infinite');    % Set style
                h = rotate3d(app.UIAxes);                 % Create rotate3d-handle
                h.ActionPostCallback = @(~, ~) RotationCallback(app,c);
                h.Enable = 'on';              % no need to click the UI-button
            end
                
                
            drawnow;
            app.Lamp.Color = [0,1,0];
            app.LampLabel.Text = " ";
        end

        % Value changed function: Topology
        function TopologyValueChanged(app, event)
            if contains(app.Topology2.Value,"Curved") || contains(app.Topology.Value,"Curved") %Curve points should be enabled if any curve exists
                app.Curvepoints.Enable = "on";
            else
                app.Curvepoints.Enable = "off";
            end
            app.curve_control.Enable = "off";
            app.Hollow.Enable = "on";
            if strcmp(app.Topology.Value, "Custom")
                app.StartPointX.Enable = "on";
                app.StartPointY.Enable = "on";
                app.StartPointZ.Enable = "on";
                app.EndPointX.Enable = "on";
                app.EndPointY.Enable = "on";
                app.EndPointZ.Enable = "on";
                app.AddStrut.Enable = "on";
                app.RemoveStrut.Enable = "on";
                app.NumberOfStruts.Enable = "on";
                app.CustomBrowseButton.Enable = "on";
                app.ImportCustomCell.Enable = "on";
                
                if ~isempty(app.customStart)
                    app.SaveCustom.Enable = "on";
                else
                    app.SaveCustom.Enable = "off";
                end
            
            else
                if contains(app.Topology.Value,"Curved")
                    if strcmp(app.Topology.Value,"FCC-Curved")
                        app.curve_control.Value = 24;
                    else
                        app.curve_control.Value = 10;
                    end
                    app.curve_control.Enable = "on";
                end

                if contains(app.Topology.Value,"Plate")
                    app.Hollow.Enable = "off";
                    app.Hollow.Value = "Solid";
                    app.InnerDiameter.Enable = "off";
                    app.OuterDiameter.Enable = "off"	;
                    app.InnerDiameter2.Enable = "off";
                    app.OuterDiameter2.Enable = "off";
                    app.thickness.Enable = "on";
                    app.thickness_grade_2.Enable = "off";
                end
                app.StartPointX.Enable = "off";
                app.StartPointY.Enable = "off";
                app.StartPointZ.Enable = "off";
                app.EndPointX.Enable = "off";
                app.EndPointY.Enable = "off";
                app.EndPointZ.Enable = "off";
                app.AddStrut.Enable = "off";
                app.RemoveStrut.Enable = "off";
                app.NumberOfStruts.Enable = "off";
                app.CustomBrowseButton.Enable = "off";
                app.ImportCustomCell.Enable = "off";
                app.ImportCustomCell.Value = "Import Custom Cell";
                app.SaveCustom.Enable = "off";
            end
            
        end

        % Value changed function: Topology2
        function Topology2ValueChanged(app, event)
        if contains(app.Topology2.Value,"Curved") || contains(app.Topology.Value,"Curved")
            app.Curvepoints.Enable = "on";
        else
            app.Curvepoints.Enable = "off";
        end
        app.curve_control_2.Enable = "off";
        app.Hollow.Enable = "on";
            if contains(app.Topology2.Value,"Curved")
                app.curve_control_2.Enable = "on";
                if strcmp(app.Topology2.Value,"FCC-Curved")
                    app.curve_control_2.Value = 24;
                else
                    app.curve_control_2.Value = 10;
                end
            end 
            if contains(app.Topology2.Value,"Plate")
                app.Hollow.Enable = "off";
                app.Hollow.Value = "Solid";
                app.InnerDiameter.Enable = "off";
                app.OuterDiameter.Enable = "off"	;
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.thickness.Enable = "on";
                app.thickness_grade_2.Enable = "off";
            end
        end

        % Value changed function: HybridStructure
        function HybridStructureValueChanged(app, event)
            if strcmp(app.HybridStructure.Value, "Single")
                app.CellTopology2Label.Enable = 'off';
                app.Topology2.Enable = 'off';        
            else
                app.CellTopology2Label.Enable = 'on';
                app.Topology2.Enable = 'on';
            end
        end

        % Value changed function: Hollow
        function HollowValueChanged(app, event)
            if strcmp(app.Hollow.Value,"Hollow")
                app.DensityControl.Enable = "off";
                app.relativeDensity.Enable = "off";
                app.relativeDensity_grade_2.Enable = "off";
            else
                app.DensityControl.Enable = "on";
                app.relativeDensity.Enable = "off";
                app.relativeDensity_grade_2.Enable = "off";
            end

            if strcmp(app.Hollow.Value,"Hollow") && strcmp(app.DensityType.Value,"Uniform") 
                app.InnerDiameter.Enable = "on";
                app.OuterDiameter.Enable = "on"	;
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.thickness.Enable = "off";
                app.thickness_grade_2.Enable = "off";
            elseif strcmp(app.Hollow.Value,"Hollow") && strcmp(app.DensityType.Value,"Graded") 
                app.DensityControl.Value = "Thickness";
                app.InnerDiameter.Enable = "on";
                app.OuterDiameter.Enable = "on";
                app.InnerDiameter2.Enable = "on";
                app.OuterDiameter2.Enable = "on";
                app.thickness.Enable = "off";
                app.thickness_grade_2.Enable = "off";
            elseif strcmp(app.Hollow.Value,"Solid") && strcmp(app.DensityType.Value,"Uniform")
                app.InnerDiameter.Enable = "off";
                app.OuterDiameter.Enable = "off"	;
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.thickness.Enable = "on";
                app.thickness_grade_2.Enable = "off";
            elseif strcmp(app.Hollow.Value,"Solid") && strcmp(app.DensityType.Value,"Graded")
                app.InnerDiameter.Enable = "off";
                app.OuterDiameter.Enable = "off"	;
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.thickness.Enable = "on";
                app.thickness_grade_2.Enable = "on";
            end
        end

        % Value changed function: CustomBrowseButton
        function CustomBrowseButtonValueChanged(app, event)
           data = [];
            [filename,path] = uigetfile('*.txt');
            file = fullfile(path,filename);
            if ~isequal(filename, 0)  % Check if the user canceled the dialog
                app.ImportCustomCell.Value = file;
                data = readmatrix(app.ImportCustomCell.Value);
            end
            
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            h = app.UIAxes;

            quiver3(0, 0, 0, 0.2, 0, 0, 'Color', 'r', 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0.25,0,0,'x', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0.2, 0, 'Color', [0,1,0], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0.25,0,'y', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0, 0.2, 'Color', [0,0,1], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0,0.25,'z', 'color', [0,0,0]); view(h, 30, 30)
            axis(app.UIAxes,"on")
            app.UIAxes.XTickMode = "auto";
            app.UIAxes.YTickMode = "auto";
            app.UIAxes.ZTickMode = "auto";
            xlim(app.UIAxes,[-1 1])
            ylim(app.UIAxes,[-1 1])
            zlim(app.UIAxes,[-1 1])

            count = 1;
            app.customStart = [];
            app.customEnd = [];
            for i = 1:2:size(data,1)
                app.customStart(count,:) = data(i,:);
                app.customEnd(count,:) = data(i+1,:);
                line = [app.customStart(count,:);app.customEnd(count,:)];
                plot3(app.UIAxes,(line(:,1))',(line(:,2))',(line(:,3))',"b-o",'MarkerFaceColor','k')
                rotate3d(app.UIAxes,'on');
                axis(app.UIAxes, "equal");
                camlight(app.UIAxes, 'headlight');
                light(app.UIAxes,'position',[0 -1 0]);
                lighting(app.UIAxes,'flat') ;
                pbaspect(app.UIAxes,[1,1,1])
                count = count+1;
                
            end
        
                % app.StartPointX.Enable = "off";
                % app.StartPointY.Enable = "off";
                % app.StartPointZ.Enable = "off";
                % app.EndPointX.Enable = "off";
                % app.EndPointY.Enable = "off";
                % app.EndPointZ.Enable = "off";
                % app.AddStrut.Enable = "off";
                % app.RemoveStrut.Enable = "off";
                % app.NumberOfStruts.Enable = "off";
                
            hold(app.UIAxes,'off')
            
        end

        % Value changed function: AddStrut
        function AddStrutValueChanged(app, event)
            startPoints = [app.StartPointX.Value,app.StartPointY.Value,app.StartPointZ.Value];
            app.customStart = [app.customStart;startPoints];

            EndPoints = [app.EndPointX.Value,app.EndPointY.Value,app.EndPointZ.Value];
            app.customEnd = [app.customEnd;EndPoints];
            app.NumberOfStruts.Value = int2str(size(app.customStart,1));
            
            cla(app.UIAxes)
            h = app.UIAxes;
            
            hold(app.UIAxes,'on')

            quiver3(0, 0, 0, 0.2, 0, 0, 'Color', 'r', 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0.25,0,0,'x', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0.2, 0, 'Color', [0,1,0], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0.25,0,'y', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0, 0.2, 'Color', [0,0,1], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0,0.25,'z', 'color', [0,0,0]); view(h, 30, 30)
            axis(app.UIAxes,"on")
            app.UIAxes.XTickMode = "auto";
            app.UIAxes.YTickMode = "auto";
            app.UIAxes.ZTickMode = "auto";
            
            for i = 1:size(app.customStart)
                line = [app.customStart(i,:);app.customEnd(i,:)];
                plot3(app.UIAxes,(line(:,1))',(line(:,2))',(line(:,3))',"b-o",'MarkerFaceColor','k')
                rotate3d(app.UIAxes,'on');
                axis(app.UIAxes, "equal");
                camlight(app.UIAxes, 'headlight');
                light(app.UIAxes,'position',[0 -1 0]);
                lighting(app.UIAxes,'flat') ;
                pbaspect(app.UIAxes,[1,1,1])
            end
            xlim(app.UIAxes,[-1 1])
            ylim(app.UIAxes,[-1 1])
            zlim(app.UIAxes,[-1 1])

            hold(app.UIAxes,'off')

            if ~isempty(app.customStart)
                app.SaveCustom.Enable = "on";
            else
                app.SaveCustom.Enable = "off";
            end
        end

        % Value changed function: RemoveStrut
        function RemoveStrutValueChanged(app, event)
            app.customStart = app.customStart(1:end-1,:);
            app.customEnd = app.customEnd(1:end-1,:);
            app.NumberOfStruts.Value = int2str(size(app.customStart,1));

            cla(app.UIAxes)
            h = app.UIAxes;
            
            hold(app.UIAxes,'on')

            quiver3(0, 0, 0, 0.2, 0, 0, 'Color', 'r', 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0.25,0,0,'x', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0.2, 0, 'Color', [0,1,0], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0.25,0,'y', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0, 0.2, 'Color', [0,0,1], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0,0.25,'z', 'color', [0,0,0]); view(h, 30, 30)
            axis(app.UIAxes,"on")
            app.UIAxes.XTickMode = "auto";
            app.UIAxes.YTickMode = "auto";
            app.UIAxes.ZTickMode = "auto";
            
            
            for i = 1:size(app.customStart)
                line = [app.customStart(i,:);app.customEnd(i,:)];
                plot3(app.UIAxes,(line(:,1))',(line(:,2))',(line(:,3))',"b-o",'MarkerFaceColor','k')
                rotate3d(app.UIAxes,'on');
                axis(app.UIAxes, "equal");
                camlight(app.UIAxes, 'headlight');
                light(app.UIAxes,'position',[0 -1 0]);
                lighting(app.UIAxes,'flat') ;
                pbaspect(app.UIAxes,[1,1,1])
            end
            xlim(app.UIAxes,[-1 1])
            ylim(app.UIAxes,[-1 1])
            zlim(app.UIAxes,[-1 1])

            hold(app.UIAxes,'off')

            if ~isempty(app.customStart)
                app.SaveCustom.Enable = "on";
            else
                app.SaveCustom.Enable = "off";
            end
            
        end

        % Value changed function: DensityControl
        function DensityControlValueChanged(app, event)
            if strcmp(app.DensityControl.Value,"Thickness") && strcmp(app.DensityType.Value,"Uniform") && strcmp(app.Hollow.Value,"Hollow")
                app.thickness.Enable = "off";
                app.thickness_grade_2.Enable = "off";
                app.relativeDensity.Enable = 'off';
                app.relativeDensity_grade_2.Enable = 'off';
                app.InnerDiameter.Enable = "on";
                app.OuterDiameter.Enable = "on";
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.coefficientA_grade.Enable = "off";
                app.coefficientB_grade.Enable = "off";
                app.coefficientC_grade.Enable = "off";
                app.coefficientD_grade.Enable = "off";
                app.GradingFormula.Enable = "off";
                app.GradingEquation.Enable = "off";
                app.EquationLabel.Enable = "off";
                app.CoefficientLabel.Enable = "off";
                app.RelativePosition_grade.Enable = "off";
                app.positionX_grade.Enable = "off";
                app.positionY_grade.Enable = "off";
                app.positionZ_grade.Enable = "off";
            elseif strcmp(app.DensityControl.Value,"Thickness") && strcmp(app.DensityType.Value,"Graded") && strcmp(app.Hollow.Value,"Hollow")
                app.thickness.Enable = "off";
                app.thickness_grade_2.Enable = "off";
                app.relativeDensity.Enable = 'off';
                app.relativeDensity_grade_2.Enable = 'off';
                app.InnerDiameter.Enable = "on";
                app.OuterDiameter.Enable = "on";
                app.InnerDiameter2.Enable = "on";
                app.OuterDiameter2.Enable = "on";
                app.coefficientA_grade.Enable = "on";
                app.coefficientB_grade.Enable = "on";
                app.coefficientC_grade.Enable = "on";
                app.coefficientD_grade.Enable = "on";
                app.GradingFormula.Enable = "on";
                app.GradingEquation.Enable = "on";
                app.EquationLabel.Enable = "on";
                app.CoefficientLabel.Enable = "on";
                app.RelativePosition_grade.Enable = "on";
                app.positionX_grade.Enable = "on";
                app.positionY_grade.Enable = "on";
                app.positionZ_grade.Enable = "on";
            elseif strcmp(app.DensityControl.Value,"Thickness") && strcmp(app.DensityType.Value,"Uniform") && strcmp(app.Hollow.Value,"Solid")
                app.thickness.Enable = "on";
                app.thickness_grade_2.Enable = "off";
                app.relativeDensity.Enable = 'off';
                app.relativeDensity_grade_2.Enable = 'off';
                app.InnerDiameter.Enable = "off";
                app.OuterDiameter.Enable = "off";
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.coefficientA_grade.Enable = "off";
                app.coefficientB_grade.Enable = "off";
                app.coefficientC_grade.Enable = "off";
                app.coefficientD_grade.Enable = "off";
                app.GradingFormula.Enable = "off";
                app.GradingEquation.Enable = "off";
                app.EquationLabel.Enable = "off";
                app.CoefficientLabel.Enable = "off";
                app.RelativePosition_grade.Enable = "off";
                app.positionX_grade.Enable = "off";
                app.positionY_grade.Enable = "off";
                app.positionZ_grade.Enable = "off";
            elseif strcmp(app.DensityControl.Value,"Thickness") && strcmp(app.DensityType.Value,"Graded") && strcmp(app.Hollow.Value,"Solid")
                app.thickness.Enable = "on";
                app.thickness_grade_2.Enable = "on";
                app.relativeDensity.Enable = 'off';
                app.relativeDensity_grade_2.Enable = 'off';
                app.InnerDiameter.Enable = "off";
                app.OuterDiameter.Enable = "off";
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.coefficientA_grade.Enable = "on";
                app.coefficientB_grade.Enable = "on";
                app.coefficientC_grade.Enable = "on";
                app.coefficientD_grade.Enable = "on";
                app.GradingFormula.Enable = "on";
                app.GradingEquation.Enable = "on";
                app.EquationLabel.Enable = "on";
                app.CoefficientLabel.Enable = "on";
                app.RelativePosition_grade.Enable = "on";
                app.positionX_grade.Enable = "on";
                app.positionY_grade.Enable = "on";
                app.positionZ_grade.Enable = "on";
            elseif strcmp(app.DensityControl.Value,"Relative Density") && strcmp(app.DensityType.Value,"Uniform") 
                app.thickness.Enable = "off";
                app.thickness_grade_2.Enable = "off";
                app.relativeDensity.Enable = 'on';
                app.relativeDensity_grade_2.Enable = 'off';
                app.InnerDiameter.Enable = "off";
                app.OuterDiameter.Enable = "off";
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.coefficientA_grade.Enable = "off";
                app.coefficientB_grade.Enable = "off";
                app.coefficientC_grade.Enable = "off";
                app.coefficientD_grade.Enable = "off";
                app.GradingFormula.Enable = "off";
                app.GradingEquation.Enable = "off";
                app.EquationLabel.Enable = "off";
                app.CoefficientLabel.Enable = "off";
                app.RelativePosition_grade.Enable = "off";
                app.positionX_grade.Enable = "off";
                app.positionY_grade.Enable = "off";
                app.positionZ_grade.Enable = "off";
            elseif strcmp(app.DensityControl.Value,"Relative Density") && strcmp(app.DensityType.Value,"Graded") 
                app.thickness.Enable = "off";
                app.thickness_grade_2.Enable = "off";
                app.relativeDensity.Enable = 'on';
                app.relativeDensity_grade_2.Enable = "on";
                app.InnerDiameter.Enable = "off";
                app.OuterDiameter.Enable = "off";
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.coefficientA_grade.Enable = "on";
                app.coefficientB_grade.Enable = "on";
                app.coefficientC_grade.Enable = "on";
                app.coefficientD_grade.Enable = "on";
                app.GradingFormula.Enable = "on";
                app.GradingEquation.Enable = "on";
                app.EquationLabel.Enable = "on";
                app.CoefficientLabel.Enable = "on";
                app.RelativePosition_grade.Enable = "on";
                app.positionX_grade.Enable = "on";
                app.positionY_grade.Enable = "on";
                app.positionZ_grade.Enable = "on";
            else
            end
            
        end

        % Value changed function: DensityType
        function DensityTypeValueChanged(app, event)
            if strcmp(app.DensityControl.Value,"Thickness") && strcmp(app.DensityType.Value,"Uniform") && strcmp(app.Hollow.Value,"Hollow")
                app.thickness.Enable = "off";
                app.thickness_grade_2.Enable = "off";
                app.relativeDensity.Enable = 'off';
                app.relativeDensity_grade_2.Enable = 'off';
                app.InnerDiameter.Enable = "on";
                app.OuterDiameter.Enable = "on";
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.coefficientA_grade.Enable = "off";
                app.coefficientB_grade.Enable = "off";
                app.coefficientC_grade.Enable = "off";
                app.coefficientD_grade.Enable = "off";
                app.GradingFormula.Enable = "off";
                app.GradingEquation.Enable = "off";
                app.EquationLabel.Enable = "off";
                app.CoefficientLabel.Enable = "off";
                app.RelativePosition_grade.Enable = "off";
                app.positionX_grade.Enable = "off";
                app.positionY_grade.Enable = "off";
                app.positionZ_grade.Enable = "off";
            elseif strcmp(app.DensityType.Value,"Graded") && strcmp(app.Hollow.Value,"Hollow")
                app.thickness.Enable = "off";
                app.thickness_grade_2.Enable = "off";
                app.relativeDensity.Enable = 'off';
                app.relativeDensity_grade_2.Enable = 'off';
                app.InnerDiameter.Enable = "on";
                app.OuterDiameter.Enable = "on";
                app.InnerDiameter2.Enable = "on";
                app.OuterDiameter2.Enable = "on";
                app.coefficientA_grade.Enable = "on";
                app.coefficientB_grade.Enable = "on";
                app.coefficientC_grade.Enable = "on";
                app.coefficientD_grade.Enable = "on";
                app.GradingFormula.Enable = "on";
                app.GradingEquation.Enable = "on";
                app.EquationLabel.Enable = "on";
                app.CoefficientLabel.Enable = "on";
                app.RelativePosition_grade.Enable = "on";
                app.positionX_grade.Enable = "on";
                app.positionY_grade.Enable = "on";
                app.positionZ_grade.Enable = "on";
            elseif strcmp(app.DensityControl.Value,"Thickness") && strcmp(app.DensityType.Value,"Uniform") && strcmp(app.Hollow.Value,"Solid")
                app.thickness.Enable = "on";
                app.thickness_grade_2.Enable = "off";
                app.relativeDensity.Enable = 'off';
                app.relativeDensity_grade_2.Enable = 'off';
                app.InnerDiameter.Enable = "off";
                app.OuterDiameter.Enable = "off";
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.coefficientA_grade.Enable = "off";
                app.coefficientB_grade.Enable = "off";
                app.coefficientC_grade.Enable = "off";
                app.coefficientD_grade.Enable = "off";
                app.GradingFormula.Enable = "off";
                app.GradingEquation.Enable = "off";
                app.EquationLabel.Enable = "off";
                app.CoefficientLabel.Enable = "off";
                app.RelativePosition_grade.Enable = "off";
                app.positionX_grade.Enable = "off";
                app.positionY_grade.Enable = "off";
                app.positionZ_grade.Enable = "off";
            elseif strcmp(app.DensityControl.Value,"Thickness") && strcmp(app.DensityType.Value,"Graded") && strcmp(app.Hollow.Value,"Solid")
                app.thickness.Enable = "on";
                app.thickness_grade_2.Enable = "on";
                app.relativeDensity.Enable = 'off';
                app.relativeDensity_grade_2.Enable = 'off';
                app.InnerDiameter.Enable = "off";
                app.OuterDiameter.Enable = "off";
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.coefficientA_grade.Enable = "on";
                app.coefficientB_grade.Enable = "on";
                app.coefficientC_grade.Enable = "on";
                app.coefficientD_grade.Enable = "on";
                app.GradingFormula.Enable = "on";
                app.GradingEquation.Enable = "on";
                app.EquationLabel.Enable = "on";
                app.CoefficientLabel.Enable = "on";
                app.RelativePosition_grade.Enable = "on";
                app.positionX_grade.Enable = "on";
                app.positionY_grade.Enable = "on";
                app.positionZ_grade.Enable = "on";
            elseif strcmp(app.DensityControl.Value,"Relative Density") && strcmp(app.DensityType.Value,"Uniform") 
                app.thickness.Enable = "off";
                app.thickness_grade_2.Enable = "off";
                app.relativeDensity.Enable = 'on';
                app.relativeDensity_grade_2.Enable = 'off';
                app.InnerDiameter.Enable = "off";
                app.OuterDiameter.Enable = "off";
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.coefficientA_grade.Enable = "off";
                app.coefficientB_grade.Enable = "off";
                app.coefficientC_grade.Enable = "off";
                app.coefficientD_grade.Enable = "off";
                app.GradingFormula.Enable = "off";
                app.GradingEquation.Enable = "off";
                app.EquationLabel.Enable = "off";
                app.CoefficientLabel.Enable = "off";
                app.RelativePosition_grade.Enable = "off";
                app.positionX_grade.Enable = "off";
                app.positionY_grade.Enable = "off";
                app.positionZ_grade.Enable = "off";
            elseif strcmp(app.DensityControl.Value,"Relative Density") && strcmp(app.DensityType.Value,"Graded") 
                app.thickness.Enable = "off";
                app.thickness_grade_2.Enable = "off";
                app.relativeDensity.Enable = 'on';
                app.relativeDensity_grade_2.Enable = "on";
                app.InnerDiameter.Enable = "off";
                app.OuterDiameter.Enable = "off";
                app.InnerDiameter2.Enable = "off";
                app.OuterDiameter2.Enable = "off";
                app.coefficientA_grade.Enable = "on";
                app.coefficientB_grade.Enable = "on";
                app.coefficientC_grade.Enable = "on";
                app.coefficientD_grade.Enable = "on";
                app.GradingFormula.Enable = "on";
                app.GradingEquation.Enable = "on";
                app.EquationLabel.Enable = "on";
                app.CoefficientLabel.Enable = "on";
                app.RelativePosition_grade.Enable = "on";
                app.positionX_grade.Enable = "on";
                app.positionY_grade.Enable = "on";
                app.positionZ_grade.Enable = "on";
            end
            
        end

        % Value changed function: relativeDensity
        function relativeDensityValueChanged(app, event)
            try
                app.stopFlag = 0;
                app.Lamp.Color = [1,1,0];
                app.LampLabel.Text = "Estimating...";
                drawnow;
        
                relDensity = app.relativeDensity.Value;
                thicknessGuess = relDensity*2*app.cellX.Value/3;
        
                max_iterations = 100;
        
                tolerance = 0.00001;
        
                for i = 1:max_iterations
                    drawnow;
                    if app.stopFlag == 0
                    [f,xx,yy,zz] = GenerateStructure(app,app.cellX.Value,app.cellY.Value,app.cellZ.Value,app.cellX.Value,app.cellY.Value,app.cellZ.Value,app.Gridpoints.Value,app.Topology.Value,app.Topology2.Value,app.Curvepoints.Value,app.curve_control.Value,app.curve_control_2.Value,app.customStart,app.customEnd,thicknessGuess,app.thickness_grade_2.Value, ...
                            app.HybridStructure.Value,"Solid",[],[],[],[],"No",app.fileName.Value,"No",app.height.Value,"Uniform",app.GradingEquation.Value,app.coefficientA_grade.Value,app.coefficientB_grade.Value,app.coefficientC_grade.Value,app.coefficientD_grade.Value,app.positionX_grade.Value,app.positionY_grade.Value,app.positionZ_grade.Value);
                    end
        
                    drawnow;
                    if app.stopFlag == 0
                        [face1, vertex1] = isosurface(xx,yy,zz,f,0);
                    end
        
                    drawnow;
                    if app.stopFlag == 0
                        [face2, vertex2] = isocaps(xx,yy,zz,f,0);
                        F = [face1; length(vertex1(:,1)) + face2]; %concatenates the face arrays which represent the faces of the resulting solid
                        V = [vertex1; vertex2]; 
                        V = [V(:,1)*app.cellX.Value V(:,2)*app.cellY.Value V(:,3)*app.cellZ.Value]; 
                        v_ini = app.cellX.Value*app.cellY.Value*app.cellZ.Value;
                        lattice_volume = abs(stlVolume(V',F'));
                        rd = lattice_volume/v_ini;
                    end
                    error = relDensity-rd;
                    if abs(error) < tolerance
                        break
                    else
                        thicknessGuess = abs(thicknessGuess + error*2*app.cellX.Value/3);
                    end
                end
                app.thickness.Value = thicknessGuess;
                app.Lamp.Color = [0,1,0];
                app.LampLabel.Text = " ";
        
            catch ME
                if app.stopFlag == 0
                app.Lamp.Color = [1,0,0];
                app.LampLabel.Text = 'Error! Check log';
                if exist("error_log",'dir') == 0
                    mkdir error_log
                else
                end
                logFile = fopen('.\error_log\logfile.txt', 'a');
                fprintf(logFile, '%s\n', ME.message);
                rethrow(ME)
                else
                    drawnow;
                    app.Lamp.Color = [0,1,0];
                    app.LampLabel.Text = " ";
                end
            end
        end

        % Value changed function: relativeDensity_grade_2
        function relativeDensity_grade_2ValueChanged(app, event)
            try
                app.stopFlag = 0;
                app.Lamp.Color = [1,1,0];
                app.LampLabel.Text = "Estimating...";
                drawnow;
        
                relDensity = app.relativeDensity_grade_2.Value;
                thicknessGuess = relDensity*2*app.cellX.Value/3;
        
                max_iterations = 1000;
        
                tolerance = 0.00001;
        
                for i = 1:max_iterations
                    
                    drawnow;
                    if app.stopFlag == 0
                    [f,xx,yy,zz] = GenerateStructure(app,app.cellX.Value,app.cellY.Value,app.cellZ.Value,app.cellX.Value,app.cellY.Value,app.cellZ.Value,app.Gridpoints.Value,app.Topology.Value,app.Topology2.Value,app.Curvepoints.Value,app.curve_control.Value,app.curve_control_2.Value,app.customStart,app.customEnd,thicknessGuess,app.thickness_grade_2.Value,...
                            app.HybridStructure.Value,"Solid",[],[],[],[],"No",app.fileName.Value,"No",app.height.Value,"Uniform",app.GradingEquation.Value,app.coefficientA_grade.Value,app.coefficientB_grade.Value,app.coefficientC_grade.Value,app.coefficientD_grade.Value,app.positionX_grade.Value,app.positionY_grade.Value,app.positionZ_grade.Value);
                    end
        
                    drawnow;
                    if app.stopFlag == 0
                        [face1, vertex1] = isosurface(xx,yy,zz,f,0);
                    end
        
                    drawnow;
                    if app.stopFlag == 0
                        [face2, vertex2] = isocaps(xx,yy,zz,f,0);
                        F = [face1; length(vertex1(:,1)) + face2]; %concatenates the face arrays which represent the faces of the resulting solid
                        V = [vertex1; vertex2]; 
                        V = [V(:,1)*app.cellX.Value V(:,2)*app.cellY.Value V(:,3)*app.cellZ.Value]; 
                        v_ini = app.cellX.Value*app.cellY.Value*app.cellZ.Value;
                        lattice_volume = abs(stlVolume(V',F'));
                        rd = lattice_volume/v_ini;
                    end
                    error = relDensity-rd;
                    if abs(error) < tolerance
                        break
                    else
                        thicknessGuess = abs(thicknessGuess + error*2*app.cellX.Value/3);
                    end
                end
                app.thickness_grade_2.Value = thicknessGuess;
                app.Lamp.Color = [0,1,0];
                app.LampLabel.Text = " ";
            
            catch ME
                if app.stopFlag == 0
                app.Lamp.Color = [1,0,0];
                app.LampLabel.Text = 'Error! Check log';
                errorFunction(app,ME.message)
                else
                    drawnow;
                    app.Lamp.Color = [0,1,0];
                    app.LampLabel.Text = " ";
                end
            end
        end

        % Value changed function: GradingEquation
        function GradingEquationValueChanged(app, event)
            if strcmp(app.GradingEquation.Value,"Linear")
                app.GradingFormula.Value = "AX + BY + CZ + D";
            elseif strcmp(app.GradingEquation.Value,"Quadratic")
                app.GradingFormula.Value = "AX^2 + BY^2 + CZ^2 + D";
            elseif strcmp(app.GradingEquation.Value,"Cubic")
                app.GradingFormula.Value = "AX^3 + BY^3 + CZ^3 + D";
            elseif strcmp(app.GradingEquation.Value,"Circular")
                app.GradingFormula.Value = "sqrt(AX^2 + BY^2 + CZ^2) - R";
                app.radius_parameter.Enable = "on";
            elseif strcmp(app.GradingEquation.Value,"Exponential")
                app.GradingFormula.Value = "e^(AX) + e^(BY) + e^(CZ)";
            elseif strcmp(app.GradingEquation.Value,"Trigonometric - Sin")
                app.GradingFormula.Value = "sin(AX) + sin(BY) + sin(CZ)";
            elseif strcmp(app.GradingEquation.Value,"Trigonometric - Cosine")
                app.GradingFormula.Value = "cos(AX) + cos(BY) + cos(CZ)";
            end     
        end

        % Value changed function: Plot
        function PlotValueChanged(app, event)
            try
                tic
                app.stopFlag = 0;
                app.Lamp.Color = [1,1,0];
                app.LampLabel.Text = "Running...";
                drawnow;

                %% Generate Structure
                [f,xx,yy,zz] = GenerateStructure(app,app.cuboidX.Value,app.cuboidY.Value,app.cuboidZ.Value,app.cellX.Value,app.cellY.Value,app.cellZ.Value,app.Gridpoints.Value,app.Topology.Value,app.Topology2.Value,app.Curvepoints.Value,app.curve_control.Value,app.curve_control_2.Value,app.customStart,app.customEnd,app.thickness.Value,app.thickness_grade_2.Value, ...
                    app.HybridStructure.Value,app.Hollow.Value,app.OuterDiameter.Value,app.InnerDiameter.Value,app.OuterDiameter2.Value,app.InnerDiameter2.Value,app.ImportedMesh.Value,app.fileName.Value,app.CompressiveSample.Value,app.height.Value,app.DensityType.Value,app.GradingEquation.Value,app.radius_parameter.Value,app.coefficientA_grade.Value,app.coefficientB_grade.Value, ...
                    app.coefficientC_grade.Value,app.coefficientD_grade.Value,app.positionX_grade.Value,app.positionY_grade.Value,app.positionZ_grade.Value);
                
                drawnow;
                if app.stopFlag == 0
                    [face1, vertex1] = isosurface(xx,yy,zz,f,0);
                end

                drawnow;
                if app.stopFlag == 0
                    [face2, vertex2] = isocaps(xx,yy,zz,f,0);
                    F = [face1; length(vertex1(:,1)) + face2]; %concatenates the face arrays which represent the faces of the resulting solid
                    V = [vertex1; vertex2]; 

                    if strcmpi(app.ImportedMesh.Value,'Yes')
                        Name = app.fileName.Value;
                        if contains(Name,'.stl') || contains(Name,'.STL')
                            inp = Name;
                        else
                            inp= strcat(Name, '.stl');
                        end
                        drawnow;
                        if app.stopFlag == 0
                           nd = stlread(inp);                                                              % read the imported mesh domain
                           ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                           v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
                        end
                    else
                        v_ini = app.cuboidX.Value*app.cuboidY.Value*app.cuboidZ.Value;
                        V = [V(:,1)*app.cellX.Value V(:,2)*app.cellY.Value V(:,3)*app.cellZ.Value]; 
                    end
                        lattice_volume = abs(stlVolume(V',F'));
                        rd = sprintf('%.4f', lattice_volume/v_ini);
                        app.exactRelativeDensity.Enable = "on";
                        app.exactRelativeDensity.Value = rd;
                        app.LatticeVolume.Enable = "on";
                        app.LatticeVolume.Value = sprintf('%.4f',lattice_volume/1000);
                end

                %% Plotting Surfaces
                drawnow;
                if app.stopFlag == 0
                    app.UIAxes.XTickMode = "auto";
                    app.UIAxes.YTickMode = "auto";
                    app.UIAxes.ZTickMode = "auto";
                    trisurf(F,V(:,1),V(:,2),V(:,3), 'Facecolor', '[1, 0.341, 0.200]','Edgecolor','none','Parent',app.UIAxes);
                    axis(app.UIAxes, "equal");
                    c = camlight(app.UIAxes, 'headlight');
                    light(app.UIAxes,'position',[0 -1 0]);
                    lighting(app.UIAxes,'flat') ;
                    pbaspect(app.UIAxes,[1,1,1])
                    set(c,'style','infinite');    % Set style
                    h = rotate3d(app.UIAxes);                 % Create rotate3d-handle
                    h.ActionPostCallback = @(~, ~) RotationCallback(app,c);
                    h.Enable = 'on';              % no need to click the UI-button
                end
                
                
                drawnow;
                app.Lamp.Color = [0,1,0];
                app.LampLabel.Text = " ";

                app.Nodes.Enable = "on";
                app.Nodes.Value = sprintf('%d',size(f,1)*size(f,2)*size(f,3));
                app.Runtime.Enable = "on";
                app.Runtime.Value = sprintf('%.4f',toc);
                app.Faces = F;
                app.Vertices = V;

            catch ME
                 if app.stopFlag == 0
                    app.Lamp.Color = [1,0,0];
                    app.LampLabel.Text = 'Error! Check log';

                    errorFunction(app,ME.message)


                else
                    drawnow;
                    app.Lamp.Color = [0,1,0];
                    app.LampLabel.Text = " ";
                end
            end
        end

        % Button pushed function: ExportSTL
        function ExportSTLButtonPushed(app, event)
            try
                app.stopFlag = 0;
                app.Lamp.Color = [1,1,0];
                app.LampLabel.Text = "Running...";
                drawnow;
                
                %% Export Structure
                if app.Faces == 0
                    app.LampLabel.Text = "No structure to export";
                    %period(1);
                    %app.LampLabel.Text = "";
                else
                drawnow;
                if ~app.stopFlag
                    [file, path] = uiputfile('.stl','Save As');
                    if ~isequal(file, 0)  % Check if the user canceled the dialog
                        NameofFile = fullfile(path,file);
                        stlwrite2(NameofFile, app.Faces, app.Vertices); %creates the object on an stl file
                    else
                    end
                end
                
                app.LampLabel.Text = " ";

                end
                
                app.Lamp.Color = [0,1,0];

            catch ME
                if app.stopFlag == 0
                    app.Lamp.Color = [1,0,0];
                    app.LampLabel.Text = 'Error! Check log';
                    errorFunction(app,ME.message)
                else
                    drawnow;
                    app.Lamp.Color = [0,1,0];
                    app.LampLabel.Text = " ";
                end
            end
        end

        % Button pushed function: SaveCustom
        function SaveCustomButtonPushed(app, event)
            if app.stopFlag == 0
                customCell = [];
                for i = 1:size(app.customStart,1)
                    customCell = [customCell;app.customStart(i,:);app.customEnd(i,:)];
                end
                %fileID = fopen('Custom Cell.txt', 'w');

                [file, path] = uiputfile('.txt','Save As');
                if ~isequal(file, 0)  % Check if the user canceled the dialog
                    NameofFile = fullfile(path,file);
                    fileID = fopen(NameofFile, 'w');
                else
                end

                if fileID == -1
                    errorFunction(app,'Failed to open file. Check the file path and permissions.');
                end
                
                fprintf(fileID, '%d %d %d\n', customCell');
                fclose(fileID);
                app.LampLabel.Text = "Cell Saved";
            end
        end

        % Value changed function: Stop
        function StopValueChanged(app, event)
            if strcmp(app.LampLabel.Text, "Running...")
                app.stopFlag = 1;
                app.Lamp.Color = [1,0,0];
                app.LampLabel.Text = "Stopping...";
                pause(1)
            end
        end

        % Value changed function: Topology_2
        function Topology_2ValueChanged(app, event)
             if contains(app.Topology2_2.Value,"Curved") || contains(app.Topology_2.Value,"Curved") %Curve points should be enabled if any curve exists
                app.Curvepoints_2.Enable = "on";
            else
                app.Curvepoints_2.Enable = "off";
            end
            app.curve_control_2.Enable = "off";
            app.Hollow_2.Enable = "on";
            if strcmp(app.Topology_2.Value, "Custom")
                app.StartPointX_2.Enable = "on";
                app.StartPointY_2.Enable = "on";
                app.StartPointZ_2.Enable = "on";
                app.EndPointX_2.Enable = "on";
                app.EndPointY_2.Enable = "on";
                app.EndPointZ_2.Enable = "on";
                app.AddStrut_2.Enable = "on";
                app.RemoveStrut_2.Enable = "on";
                app.NumberOfStruts_2.Enable = "on";
                app.CustomBrowseButton_2.Enable = "on";
                app.ImportCustomCell_2.Enable = "on";
                
                if ~isempty(app.customStart)
                    app.SaveCustom.Enable = "on";
                else
                    app.SaveCustom.Enable = "off";
                end
            
            else
                if contains(app.Topology_2.Value,"Curved")
                    if strcmp(app.Topology_2.Value,"FCC-Curved")
                        app.curve_control_3.Value = 24;
                    else
                        app.curve_control_3.Value = 10;
                    end
                    app.curve_control_3.Enable = "on";
                end

                if contains(app.Topology.Value,"Plate")
                    app.Hollow_2.Enable = "off";
                    app.Hollow_2.Value = "Solid";
                    app.InnerDiameter_2.Enable = "off";
                    app.OuterDiameter_2.Enable = "off"	;
                    app.InnerDiameter2_2.Enable = "off";
                    app.OuterDiameter2_2.Enable = "off";
                    app.thickness_2.Enable = "on";
                    app.thickness_grade_3.Enable = "off";
                end
                app.StartPointX_2.Enable = "off";
                app.StartPointY_2.Enable = "off";
                app.StartPointZ_2.Enable = "off";
                app.EndPointX_2.Enable = "off";
                app.EndPointY_2.Enable = "off";
                app.EndPointZ_2.Enable = "off";
                app.AddStrut_2.Enable = "off";
                app.RemoveStrut_2.Enable = "off";
                app.NumberOfStruts_2.Enable = "off";
                app.CustomBrowseButton_2.Enable = "off";
                app.ImportCustomCell_2.Enable = "off";
                app.ImportCustomCell_2.Value = "Import Custom Cell";
                app.SaveCustom.Enable = "off";
            end
            
        end

        % Value changed function: Topology2_2
        function Topology2_2ValueChanged(app, event)
            if contains(app.Topology2_2.Value,"Curved") || contains(app.Topology_2.Value,"Curved")
            app.Curvepoints_2.Enable = "on";
        else
            app.Curvepoints_2.Enable = "off";
        end
        app.curve_control_4.Enable = "off";
        app.Hollow.Enable = "on";
            if contains(app.Topology2_2.Value,"Curved")
                app.curve_control_4.Enable = "on";
                if strcmp(app.Topology2_2.Value,"FCC-Curved")
                    app.curve_control_2_2.Value = 24;
                else
                    app.curve_control_4.Value = 10;
                end
            end 
            if contains(app.Topology2_2.Value,"Plate")
                app.Hollow_2.Enable = "off";
                app.Hollow_2.Value = "Solid";
                app.InnerDiameter_2.Enable = "off";
                app.OuterDiameter_2.Enable = "off"	;
                app.InnerDiameter2_2.Enable = "off";
                app.OuterDiameter2_2.Enable = "off";
                app.thickness_2.Enable = "on";
                app.thickness_grade_3.Enable = "off";
            end
        end

        % Value changed function: HybridStructure_2
        function HybridStructure_2ValueChanged(app, event)
            if strcmp(app.HybridStructure_2.Value, "Single")
                app.CellTopology2Label_2.Enable = 'off';
                app.Topology2_2.Enable = 'off';        
            else
                app.CellTopology2Label_2.Enable = 'on';
                app.Topology2_2.Enable = 'on';
            end
        end

        % Value changed function: Hollow_2
        function Hollow_2ValueChanged(app, event)
            if strcmp(app.Hollow_2.Value,"Hollow")
                app.DensityControl_2.Enable = "off";
                app.relativeDensity_2.Enable = "off";
                app.relativeDensity_grade_3.Enable = "off";
            else
                app.DensityControl_2.Enable = "on";
                app.relativeDensity_2.Enable = "off";
                app.relativeDensity_grade_3.Enable = "off";
            end

            if strcmp(app.Hollow_2.Value,"Hollow") && strcmp(app.DensityType_2.Value,"Uniform") 
                app.InnerDiameter_2.Enable = "on";
                app.OuterDiameter_2.Enable = "on"	;
                app.InnerDiameter2_2.Enable = "off";
                app.OuterDiameter2_2.Enable = "off";
                app.thickness_2.Enable = "off";
                app.thickness_grade_3.Enable = "off";
            elseif strcmp(app.Hollow_2.Value,"Hollow") && strcmp(app.DensityType_2.Value,"Graded") 
                app.DensityControl_2.Value = "Thickness";
                app.InnerDiameter_2.Enable = "on";
                app.OuterDiameter_2.Enable = "on";
                app.InnerDiameter2_2.Enable = "on";
                app.OuterDiameter2_2.Enable = "on";
                app.thickness_2.Enable = "off";
                app.thickness_grade_3.Enable = "off";
            elseif strcmp(app.Hollow_2.Value,"Solid") && strcmp(app.DensityType_2.Value,"Uniform")
                app.InnerDiameter_2.Enable = "off";
                app.OuterDiameter_2.Enable = "off"	;
                app.InnerDiameter2_2.Enable = "off";
                app.OuterDiameter2_2.Enable = "off";
                app.thickness_2.Enable = "on";
                app.thickness_grade_3.Enable = "off";
            elseif strcmp(app.Hollow_2.Value,"Solid") && strcmp(app.DensityType_2.Value,"Graded")
                app.InnerDiameter_2.Enable = "off";
                app.OuterDiameter_2.Enable = "off"	;
                app.InnerDiameter2_2.Enable = "off";
                app.OuterDiameter2_2.Enable = "off";
                app.thickness_2.Enable = "on";
                app.thickness_grade_3.Enable = "on";
            end
        end

        % Value changed function: CustomBrowseButton_2
        function CustomBrowseButton_2ValueChanged(app, event)
            data = [];
            [filename,path] = uigetfile('*.txt');
            file = fullfile(path,filename);
            if ~isequal(filename, 0)  % Check if the user canceled the dialog
                app.ImportCustomCell.Value = file;
                data = readmatrix(app.ImportCustomCell.Value);
            end
            
            cla(app.UIAxes_2)
            hold(app.UIAxes_2,'on')
            h = app.UIAxes_2;

            quiver3(0, 0, 0, 0.2, 0, 0, 'Color', 'r', 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0.25,0,0,'x', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0.2, 0, 'Color', [0,1,0], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0.25,0,'y', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0, 0.2, 'Color', [0,0,1], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0,0.25,'z', 'color', [0,0,0]); view(h, 30, 30)
            axis(app.UIAxes_2,"on")
            app.UIAxes_2.XTickMode = "auto";
            app.UIAxes_2.YTickMode = "auto";
            app.UIAxes_2.ZTickMode = "auto";
            xlim(app.UIAxes_2,[-1 1])
            ylim(app.UIAxes_2,[-1 1])
            zlim(app.UIAxes_2,[-1 1])

            count = 1;
            app.customStart = [];
            app.customEnd = [];
            for i = 1:2:size(data,1)
                app.customStart(count,:) = data(i,:);
                app.customEnd(count,:) = data(i+1,:);
                line = [app.customStart(count,:);app.customEnd(count,:)];
                plot3(app.UIAxes_2,(line(:,1))',(line(:,2))',(line(:,3))',"b-o",'MarkerFaceColor','k')
                rotate3d(app.UIAxes_2,'on');
                axis(app.UIAxes_2, "equal");
                camlight(app.UIAxes_2, 'headlight');
                light(app.UIAxes_2,'position',[0 -1 0]);
                lighting(app.UIAxes_2,'flat') ;
                pbaspect(app.UIAxes_2,[1,1,1])
                count = count+1;
                
            end
        
                % app.StartPointX.Enable = "off";
                % app.StartPointY.Enable = "off";
                % app.StartPointZ.Enable = "off";
                % app.EndPointX.Enable = "off";
                % app.EndPointY.Enable = "off";
                % app.EndPointZ.Enable = "off";
                % app.AddStrut.Enable = "off";
                % app.RemoveStrut.Enable = "off";
                % app.NumberOfStruts.Enable = "off";
                
            hold(app.UIAxes_2,'off')
            
        end

        % Value changed function: AddStrut_2
        function AddStrut_2ValueChanged(app, event)
            startPoints = [app.StartPointX_2.Value,app.StartPointY_2.Value,app.StartPointZ_2.Value];
            app.customStart = [app.customStart;startPoints];

            EndPoints = [app.EndPointX_2.Value,app.EndPointY_2.Value,app.EndPointZ_2.Value];
            app.customEnd = [app.customEnd;EndPoints];
            app.NumberOfStruts.Value = int2str(size(app.customStart,1));
            
            cla(app.UIAxes_2)
            h = app.UIAxes_2;
            
            hold(app.UIAxes_2,'on')

            quiver3(0, 0, 0, 0.2, 0, 0, 'Color', 'r', 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0.25,0,0,'x', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0.2, 0, 'Color', [0,1,0], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0.25,0,'y', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0, 0.2, 'Color', [0,0,1], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0,0.25,'z', 'color', [0,0,0]); view(h, 30, 30)
            axis(app.UIAxes_2,"on")
            app.UIAxes_2.XTickMode = "auto";
            app.UIAxes_2.YTickMode = "auto";
            app.UIAxes_2.ZTickMode = "auto";
            
            for i = 1:size(app.customStart)
                line = [app.customStart(i,:);app.customEnd(i,:)];
                plot3(app.UIAxes_2,(line(:,1))',(line(:,2))',(line(:,3))',"b-o",'MarkerFaceColor','k')
                rotate3d(app.UIAxes_2,'on');
                axis(app.UIAxes_2, "equal");
                camlight(app.UIAxes_2, 'headlight');
                light(app.UIAxes_2,'position',[0 -1 0]);
                lighting(app.UIAxes_2,'flat') ;
                pbaspect(app.UIAxes_2,[1,1,1])
            end
            xlim(app.UIAxes_2,[-1 1])
            ylim(app.UIAxes_2,[-1 1])
            zlim(app.UIAxes_2,[-1 1])

            hold(app.UIAxes_2,'off')

            if ~isempty(app.customStart)
                app.SaveCustom.Enable = "on";
            else
                app.SaveCustom.Enable = "off";
            end
        end

        % Value changed function: RemoveStrut_2
        function RemoveStrut_2ValueChanged(app, event)
            app.customStart = app.customStart(1:end-1,:);
            app.customEnd = app.customEnd(1:end-1,:);
            app.NumberOfStruts.Value = int2str(size(app.customStart,1));

            cla(app.UIAxes_2)
            h = app.UIAxes_2;
            
            hold(app.UIAxes_2,'on')

            quiver3(0, 0, 0, 0.2, 0, 0, 'Color', 'r', 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0.25,0,0,'x', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0.2, 0, 'Color', [0,1,0], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0.25,0,'y', 'color', [0,0,0]); view(h, 30, 30)
            quiver3(0, 0, 0, 0, 0, 0.2, 'Color', [0,0,1], 'LineStyle', '-', 'LineWidth', 2, 'Parent',h, 'MaxHeadSize',8, 'Clipping', 'off');
            text(h,0,0,0.25,'z', 'color', [0,0,0]); view(h, 30, 30)
            axis(app.UIAxes_2,"on")
            app.UIAxes_2.XTickMode = "auto";
            app.UIAxes_2.YTickMode = "auto";
            app.UIAxes_2.ZTickMode = "auto";
            
            
            for i = 1:size(app.customStart)
                line = [app.customStart(i,:);app.customEnd(i,:)];
                plot3(app.UIAxes_2,(line(:,1))',(line(:,2))',(line(:,3))',"b-o",'MarkerFaceColor','k')
                rotate3d(app.UIAxes_2,'on');
                axis(app.UIAxes_2, "equal");
                camlight(app.UIAxes_2, 'headlight');
                light(app.UIAxes_2,'position',[0 -1 0]);
                lighting(app.UIAxes_2,'flat') ;
                pbaspect(app.UIAxes_2,[1,1,1])
            end
            xlim(app.UIAxes_2,[-1 1])
            ylim(app.UIAxes_2,[-1 1])
            zlim(app.UIAxes_2,[-1 1])

            hold(app.UIAxes_2,'off')

            if ~isempty(app.customStart)
                app.SaveCustom.Enable = "on";
            else
                app.SaveCustom.Enable = "off";
            end
        end

        % Value changed function: DensityControl_2
        function DensityControl_2ValueChanged(app, event)
             if strcmp(app.DensityControl_2.Value,"Thickness") && strcmp(app.DensityType_2.Value,"Uniform") && strcmp(app.Hollow.Value,"Hollow")
                app.thickness_2.Enable = "off";
                app.thickness_grade_3.Enable = "off";
                app.relativeDensity_2.Enable = 'off';
                app.relativeDensity_grade_3.Enable = 'off';
                app.InnerDiameter_2.Enable = "on";
                app.OuterDiameter_2.Enable = "on";
                app.InnerDiameter2_2.Enable = "off";
                app.OuterDiameter2_2.Enable = "off";
                app.coefficientA_grade_2.Enable = "off";
                app.coefficientB_grade_2.Enable = "off";
                app.coefficientC_grade_2.Enable = "off";
                app.coefficientD_grade_2.Enable = "off";
                app.GradingFormula_2.Enable = "off";
                app.GradingEquation_2.Enable = "off";
                app.EquationLabel_2.Enable = "off";
                app.CoefficientLabel_2.Enable = "off";
                app.RelativePosition_grade_2.Enable = "off";
                app.positionX_grade_2.Enable = "off";
                app.positionY_grade_2.Enable = "off";
                app.positionZ_grade_2.Enable = "off";
            elseif strcmp(app.DensityControl_2.Value,"Thickness") && strcmp(app.DensityType_2.Value,"Graded") && strcmp(app.Hollow.Value,"Hollow")
                app.thickness_2.Enable = "off";
                app.thickness_grade_3.Enable = "off";
                app.relativeDensity_2.Enable = 'off';
                app.relativeDensity_grade_3.Enable = 'off';
                app.InnerDiameter_2.Enable = "on";
                app.OuterDiameter_2.Enable = "on";
                app.InnerDiameter2_2.Enable = "on";
                app.OuterDiameter2_2.Enable = "on";
                app.coefficientA_grade_2.Enable = "on";
                app.coefficientB_grade_2.Enable = "on";
                app.coefficientC_grade_2.Enable = "on";
                app.coefficientD_grade_2.Enable = "on";
                app.GradingFormula_2.Enable = "on";
                app.GradingEquation_2.Enable = "on";
                app.EquationLabel_2.Enable = "on";
                app.CoefficientLabel_2.Enable = "on";
                app.RelativePosition_grade_2.Enable = "on";
                app.positionX_grade_2.Enable = "on";
                app.positionY_grade_2.Enable = "on";
                app.positionZ_grade_2.Enable = "on";
            elseif strcmp(app.DensityControl_2.Value,"Thickness") && strcmp(app.DensityType_2.Value,"Uniform") && strcmp(app.Hollow.Value,"Solid")
                app.thickness_2.Enable = "on";
                app.thickness_grade_3.Enable = "off";
                app.relativeDensity_2.Enable = 'off';
                app.relativeDensity_grade_3.Enable = 'off';
                app.InnerDiameter_2.Enable = "off";
                app.OuterDiameter_2.Enable = "off";
                app.InnerDiameter2_2.Enable = "off";
                app.OuterDiameter2_2.Enable = "off";
                app.coefficientA_grade_2.Enable = "off";
                app.coefficientB_grade_2.Enable = "off";
                app.coefficientC_grade_2.Enable = "off";
                app.coefficientD_grade_2.Enable = "off";
                app.GradingFormula_2.Enable = "off";
                app.GradingEquation_2.Enable = "off";
                app.EquationLabel_2.Enable = "off";
                app.CoefficientLabel_2.Enable = "off";
                app.RelativePosition_grade_2.Enable = "off";
                app.positionX_grade_2.Enable = "off";
                app.positionY_grade_2.Enable = "off";
                app.positionZ_grade_2.Enable = "off";
            elseif strcmp(app.DensityControl_2.Value,"Thickness") && strcmp(app.DensityType_2.Value,"Graded") && strcmp(app.Hollow.Value,"Solid")
                app.thickness_2.Enable = "on";
                app.thickness_grade_3.Enable = "on";
                app.relativeDensity_2.Enable = 'off';
                app.relativeDensity_grade_3.Enable = 'off';
                app.InnerDiameter_2.Enable = "off";
                app.OuterDiameter_2.Enable = "off";
                app.InnerDiameter2_2.Enable = "off";
                app.OuterDiameter2_2.Enable = "off";
                app.coefficientA_grade_2.Enable = "on";
                app.coefficientB_grade_2.Enable = "on";
                app.coefficientC_grade_2.Enable = "on";
                app.coefficientD_grade_2.Enable = "on";
                app.GradingFormula_2.Enable = "on";
                app.GradingEquation_2.Enable = "on";
                app.EquationLabel_2.Enable = "on";
                app.CoefficientLabel_2.Enable = "on";
                app.RelativePosition_grade_2.Enable = "on";
                app.positionX_grade_2.Enable = "on";
                app.positionY_grade_2.Enable = "on";
                app.positionZ_grade_2.Enable = "on";
            elseif strcmp(app.DensityControl_2.Value,"Relative Density") && strcmp(app.DensityType_2.Value,"Uniform") 
                app.thickness_2.Enable = "off";
                app.thickness_grade_3.Enable = "off";
                app.relativeDensity_2.Enable = 'on';
                app.relativeDensity_grade_3.Enable = 'off';
                app.InnerDiameter_2.Enable = "off";
                app.OuterDiameter_2.Enable = "off";
                app.InnerDiameter2_2.Enable = "off";
                app.OuterDiameter2_2.Enable = "off";
                app.coefficientA_grade_2.Enable = "off";
                app.coefficientB_grade_2.Enable = "off";
                app.coefficientC_grade_2.Enable = "off";
                app.coefficientD_grade_2.Enable = "off";
                app.GradingFormula_2.Enable = "off";
                app.GradingEquation_2.Enable = "off";
                app.EquationLabel_2.Enable = "off";
                app.CoefficientLabel_2.Enable = "off";
                app.RelativePosition_grade_2.Enable = "off";
                app.positionX_grade_2.Enable = "off";
                app.positionY_grade_2.Enable = "off";
                app.positionZ_grade_2.Enable = "off";
            elseif strcmp(app.DensityControl_2.Value,"Relative Density") && strcmp(app.DensityType_2.Value,"Graded") 
                app.thickness_2.Enable = "off";
                app.thickness_grade_3.Enable = "off";
                app.relativeDensity_2.Enable = 'on';
                app.relativeDensity_grade_3.Enable = "on";
                app.InnerDiameter_2.Enable = "off";
                app.OuterDiameter_2.Enable = "off";
                app.InnerDiameter2_2.Enable = "off";
                app.OuterDiameter2_2.Enable = "off";
                app.coefficientA_grade_2.Enable = "on";
                app.coefficientB_grade_2.Enable = "on";
                app.coefficientC_grade_2.Enable = "on";
                app.coefficientD_grade_2.Enable = "on";
                app.GradingFormula_2.Enable = "on";
                app.GradingEquation_2.Enable = "on";
                app.EquationLabel_2.Enable = "on";
                app.CoefficientLabel_2.Enable = "on";
                app.RelativePosition_grade_2.Enable = "on";
                app.positionX_grade_2.Enable = "on";
                app.positionY_grade_2.Enable = "on";
                app.positionZ_grade_2.Enable = "on";
            else
            end
        end

        % Value changed function: GradingEquation_2
        function GradingEquation_2ValueChanged(app, event)
             if strcmp(app.GradingEquation_2.Value,"Linear")
                app.GradingFormula_2.Value = "AX + BY + CZ + D";
            elseif strcmp(app.GradingEquation_2.Value,"Quadratic")
                app.GradingFormula_2.Value = "AX^2 + BY^2 + CZ^2 + D";
            elseif strcmp(app.GradingEquation_2.Value,"Cubic")
                app.GradingFormula_2.Value = "AX^3 + BY^3 + CZ^3 + D";
            elseif strcmp(app.GradingEquation_2.Value,"Circular")
                app.GradingFormula_2.Value = "sqrt(AX^2 + BY^2 + CZ^2) - R";
                app.radius_parameter_2.Enable = "on";
            elseif strcmp(app.GradingEquation_2.Value,"Exponential")
                app.GradingFormula_2.Value = "e^(AX) + e^(BY) + e^(CZ)";
            elseif strcmp(app.GradingEquation_2.Value,"Trigonometric - Sin")
                app.GradingFormula_2.Value = "sin(AX) + sin(BY) + sin(CZ)";
            elseif strcmp(app.GradingEquation_2.Value,"Trigonometric - Cosine")
                app.GradingFormula_2.Value = "cos(AX) + cos(BY) + cos(CZ)";
            end 
        end

        % Value changed function: RunHomogenization
        function RunHomogenizationValueChanged(app, event)
    try
            app.stopFlag = 0;
                app.Lamp_2.Color = [1,1,0];
                app.LampLabel_2.Text = "Running...";
                drawnow;


            [x,y,z,c,CM,E,PR] = RunHomogenization(app,app.cellX_2.Value,app.cellY_2.Value,app.cellZ_2.Value,app.Gridpoints_2.Value,app.Topology_2.Value,app.Topology2_2.Value,app.Modulus.Value,app.Poisson.Value,...
                app.Curvepoints.Value,app.curve_control.Value,app.curve_control_2.Value,app.customStart,app.customEnd,app.thickness_2.Value,app.thickness_grade_3.Value,app.HybridStructure_2.Value,app.Hollow_2.Value,...
                app.OuterDiameter_2.Value,app.InnerDiameter_2.Value,app.OuterDiameter2_2.Value,app.InnerDiameter2_2.Value,app.DensityType_2.Value,app.GradingEquation_2.Value,app.radius_parameter_2.Value,app.coefficientA_grade.Value,app.coefficientB_grade_2.Value,...
                app.coefficientC_grade_2.Value,app.coefficientD_grade_2.Value,app.positionX_grade_2.Value,app.positionY_grade_2.Value,app.positionZ_grade_2.Value);


            %% Plotting Surfaces
                drawnow;
                if app.stopFlag == 0
                    app.UIAxes_2.XTickMode = "auto";
                    app.UIAxes_2.YTickMode = "auto";
                    app.UIAxes_2.ZTickMode = "auto";
                    surf(x,y,z,c, 'Facecolor', 'interp','Edgecolor','none','Parent',app.UIAxes_2);
                    colormap(app.UIAxes_2,"jet")
                    colorbar(app.UIAxes_2)
                    axis(app.UIAxes_2, "equal");
                    c = camlight(app.UIAxes_2, 'headlight');
                    light(app.UIAxes_2,'position',[0 -1 0]);
                    lighting(app.UIAxes_2,'flat') ;
                    pbaspect(app.UIAxes_2,[1,1,1])
                    set(c,'style','infinite');    % Set style
                    h = rotate3d(app.UIAxes_2);                 % Create rotate3d-handle
                    h.ActionPostCallback = @(~, ~) RotationCallback(app,c);
                    h.Enable = 'on';              % no need to click the UI-button
                end
                
                app.ConMatrix = CM;
                app.YModulus = E(1:3);
                app.SModulus = E(4:6);
                app.PRatio = PR;
                
                app.YM1.Value = int2str(E(1));
                app.YM2.Value = int2str(E(2));
                app.YM3.Value = int2str(E(3));
                app.SM1.Value = int2str(E(4));
                app.SM2.Value = int2str(E(5));
                app.SM3.Value = int2str(E(6));

                app.PR1.Value = num2str(PR(1));
                app.PR2.Value = num2str(PR(2));
                app.PR3.Value = num2str(PR(3));
                app.PR4.Value = num2str(PR(4));
                app.PR5.Value = num2str(PR(5));
                app.PR6.Value = num2str(PR(6));

                app.CM1.Value = num2str(CM(1,1));
                app.CM2.Value = num2str(CM(2,1));
                app.CM3.Value = num2str(CM(3,1));
                app.CM4.Value = num2str(CM(4,1));
                app.CM5.Value = num2str(CM(5,1));
                app.CM6.Value = num2str(CM(6,1));
                app.CM7.Value = num2str(CM(1,2));
                app.CM8.Value = num2str(CM(2,2));
                app.CM9.Value = num2str(CM(3,2));
                app.CM10.Value = num2str(CM(4,2));
                app.CM11.Value = num2str(CM(5,2));
                app.CM12.Value = num2str(CM(6,2));
                app.CM13.Value = num2str(CM(1,3));
                app.CM14.Value = num2str(CM(2,3));
                app.CM15.Value = num2str(CM(3,3));
                app.CM16.Value = num2str(CM(4,3));
                app.CM17.Value = num2str(CM(5,3));
                app.CM18.Value = num2str(CM(6,3));
                app.CM19.Value = num2str(CM(1,4));
                app.CM20.Value = num2str(CM(2,4));
                app.CM21.Value = num2str(CM(3,4));
                app.CM22.Value = num2str(CM(4,4));
                app.CM23.Value = num2str(CM(5,4));
                app.CM24.Value = num2str(CM(6,4));
                app.CM25.Value = num2str(CM(1,5));
                app.CM26.Value = num2str(CM(2,5));
                app.CM27.Value = num2str(CM(3,5));
                app.CM28.Value = num2str(CM(4,5));
                app.CM29.Value = num2str(CM(5,5));
                app.CM30.Value = num2str(CM(6,5));
                app.CM31.Value = num2str(CM(1,6));
                app.CM32.Value = num2str(CM(2,6));
                app.CM33.Value = num2str(CM(3,6));
                app.CM34.Value = num2str(CM(4,6));
                app.CM35.Value = num2str(CM(5,6));
                app.CM36.Value = num2str(CM(6,6));


                drawnow;
                app.Lamp_2.Color = [0,1,0];
                app.LampLabel_2.Text = " ";
    catch ME
                 if app.stopFlag == 0
                    app.Lamp_2.Color = [1,0,0];
                    app.LampLabel_2.Text = 'Error! Check log';

                    errorFunction(app,ME.message)


                else
                    drawnow;
                    app.Lamp_2.Color = [0,1,0];
                    app.LampLabel_2.Text = " ";
                end
            end
        end

        % Button pushed function: ExportProperties
        function ExportPropertiesButtonPushed(app, event)
            if app.stopFlag == 0
                
                HomProperties = { ...
                                "Constitutive Matrix", app.ConMatrix; ...
                                "Young's Modulus", app.YModulus; ...
                                "Shear Modulus", app.SModulus; ...
                                "Poisson's Ratio", app.PRatio ...
                            };
                [file, path] = uiputfile('.txt','Save As');
                if ~isequal(file, 0)  % Check if the user canceled the dialog
                    NameofFile = fullfile(path,file);
                    fileID = fopen(NameofFile, 'w');
                else
                end

                if fileID == -1
                    errorFunction(app,'Failed to open file. Check the file path and permissions.');
                end
                
                for i = 1:size(HomProperties, 1)
                    label = HomProperties{i, 1};
                    value = HomProperties{i, 2};
                    fprintf(fileID, '%s:\n', label);
                    fprintf(fileID, '%.6f\t', value);
                    fprintf(fileID, '\n');
                end


                fclose(fileID);
                app.LampLabel.Text = "Cell Saved";
            end
        end

        % Value changed function: relativeDensity_2
        function relativeDensity_2ValueChanged(app, event)
            try
                app.stopFlag = 0;
                app.Lamp.Color = [1,1,0];
                app.LampLabel.Text = "Estimating...";
                drawnow;
        
                relDensity = app.relativeDensity_2.Value;
                thicknessGuess = relDensity*2*app.cellX_2.Value/3;
        
                max_iterations = 100;
        
                tolerance = 0.00001;
        
                for i = 1:max_iterations
                    drawnow;
                    if app.stopFlag == 0
                    [f,xx,yy,zz] = GenerateStructure(app,app.cellX_2.Value,app.cellY_2.Value,app.cellZ_2.Value,app.cellX_2.Value,app.cellY_2.Value,app.cellZ_2.Value,app.Gridpoints_2.Value,app.Topology_2.Value,app.Topology2_2.Value,app.Curvepoints_2.Value,app.curve_control_3.Value,app.curve_control_4.Value,app.customStart,app.customEnd,thicknessGuess,app.thickness_grade_3.Value, ...
                            app.HybridStructure_2.Value,"Solid",[],[],[],[],"No",app.fileName.Value,"No",app.height.Value,"Uniform",app.GradingEquation_2.Value,app.coefficientA_grade_2.Value,app.coefficientB_grade_2.Value,app.coefficientC_grade_2.Value,app.coefficientD_grade_2.Value,app.positionX_grade_2.Value,app.positionY_grade_2.Value,app.positionZ_grade_2.Value);
                    end
        
                    drawnow;
                    if app.stopFlag == 0
                        [face1, vertex1] = isosurface(xx,yy,zz,f,0);
                    end
        
                    drawnow;
                    if app.stopFlag == 0
                        [face2, vertex2] = isocaps(xx,yy,zz,f,0);
                        F = [face1; length(vertex1(:,1)) + face2]; %concatenates the face arrays which represent the faces of the resulting solid
                        V = [vertex1; vertex2]; 
                        V = [V(:,1)*app.cellX_2.Value V(:,2)*app.cellY_2.Value V(:,3)*app.cellZ_2.Value]; 
                        v_ini = app.cellX_2.Value*app.cellY_2.Value*app.cellZ_2.Value;
                        lattice_volume = abs(stlVolume(V',F'));
                        rd = lattice_volume/v_ini;
                    end
                    error = relDensity-rd;
                    if abs(error) < tolerance
                        break
                    else
                        thicknessGuess = abs(thicknessGuess + error*2*app.cellX.Value/3);
                    end
                end
                app.thickness_2.Value = thicknessGuess;
                app.Lamp.Color = [0,1,0];
                app.LampLabel.Text = " ";
        
            catch ME
                if app.stopFlag == 0
                app.Lamp.Color = [1,0,0];
                app.LampLabel.Text = 'Error! Check log';
                if exist("error_log",'dir') == 0
                    mkdir error_log
                else
                end
                logFile = fopen('.\error_log\logfile.txt', 'a');
                fprintf(logFile, '%s\n', ME.message);
                rethrow(ME)
                else
                    drawnow;
                    app.Lamp.Color = [0,1,0];
                    app.LampLabel.Text = " ";
                end
            end
        end

        % Value changed function: relativeDensity_grade_3
        function relativeDensity_grade_3ValueChanged(app, event)
            try
                app.stopFlag = 0;
                app.Lamp.Color = [1,1,0];
                app.LampLabel.Text = "Estimating...";
                drawnow;
        
                relDensity = app.relativeDensity_grade_3.Value;
                thicknessGuess = relDensity*2*app.cellX_2.Value/3;
        
                max_iterations = 1000;
        
                tolerance = 0.00001;
        
                for i = 1:max_iterations
                    
                    drawnow;
                    if app.stopFlag == 0
                    [f,xx,yy,zz] = GenerateStructure(app,app.cellX_2.Value,app.cellY_2.Value,app.cellZ_2.Value,app.cellX_2.Value,app.cellY_2.Value,app.cellZ_2.Value,app.Gridpoints_2.Value,app.Topology_2.Value,app.Topology2_2.Value,app.Curvepoints_2.Value,app.curve_control_3.Value,app.curve_control_4.Value,app.customStart,app.customEnd,thicknessGuess,app.thickness_grade_3.Value, ...
                            app.HybridStructure_2.Value,"Solid",[],[],[],[],"No",app.fileName.Value,"No",app.height.Value,"Uniform",app.GradingEquation_2.Value,app.coefficientA_grade_2.Value,app.coefficientB_grade_2.Value,app.coefficientC_grade_2.Value,app.coefficientD_grade_2.Value,app.positionX_grade_2.Value,app.positionY_grade_2.Value,app.positionZ_grade_2.Value);
                    end
        
                    drawnow;
                    if app.stopFlag == 0
                        [face1, vertex1] = isosurface(xx,yy,zz,f,0);
                    end
        
                    drawnow;
                    if app.stopFlag == 0
                        [face2, vertex2] = isocaps(xx,yy,zz,f,0);
                        F = [face1; length(vertex1(:,1)) + face2]; %concatenates the face arrays which represent the faces of the resulting solid
                        V = [vertex1; vertex2]; 
                        V = [V(:,1)*app.cellX_2.Value V(:,2)*app.cellY_2.Value V(:,3)*app.cellZ_2.Value]; 
                        v_ini = app.cellX_2.Value*app.cellY_2.Value*app.cellZ_2.Value;
                        lattice_volume = abs(stlVolume(V',F'));
                        rd = lattice_volume/v_ini;
                    end
                    error = relDensity-rd;
                    if abs(error) < tolerance
                        break
                    else
                        thicknessGuess = abs(thicknessGuess + error*2*app.cellX_2.Value/3);
                    end
                end
                app.thickness_grade_3.Value = thicknessGuess;
                app.Lamp.Color = [0,1,0];
                app.LampLabel.Text = " ";
            
            catch ME
                if app.stopFlag == 0
                app.Lamp.Color = [1,0,0];
                app.LampLabel.Text = 'Error! Check log';
                errorFunction(app,ME.message)
                else
                    drawnow;
                    app.Lamp.Color = [0,1,0];
                    app.LampLabel.Text = " ";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StrutandPlatetypeLatticeGeneratorTab = uitab(app.TabGroup);
            app.StrutandPlatetypeLatticeGeneratorTab.Title = 'Strut and Plate-type Lattice Generator';
            app.UIAxes = uiaxes(app.StrutandPlatetypeLatticeGeneratorTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.Panel = uipanel(app.StrutandPlatetypeLatticeGeneratorTab);
            app.GeometryInitializationLabel = uilabel(app.Panel);
            app.GeometryInitializationLabel.Text = 'Geometry Initialization';
            app.ImportCustomDomainLabel = uilabel(app.Panel);
            app.ImportCustomDomainLabel.Tooltip = {''};
            app.ImportCustomDomainLabel.Text = 'Import Custom Domain:';
            app.ImportedMesh = uidropdown(app.Panel);
            app.ImportedMesh.Items = {'No', 'Yes'};
            app.ImportedMesh.ValueChangedFcn = createCallbackFcn(app, @ImportedMeshValueChanged, true);
            app.ImportedMesh.Value = 'No';
            app.NameofFileLabel = uilabel(app.Panel);
            app.NameofFileLabel.Tooltip = {'Provide the name of the imported mesh file or "Browse" to locate it on your computer'};
            app.NameofFileLabel.Text = 'Name of file:';
            app.fileName = uieditfield(app.Panel, 'text');
            app.fileName.Editable = 'off';
            app.BrowseButton = uibutton(app.Panel, 'state');
            app.BrowseButton.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonValueChanged, true);
            app.BrowseButton.Enable = 'off';
            app.BrowseButton.Text = 'Browse';
            app.CuboidDimmmLabel = uilabel(app.Panel);
            app.CuboidDimmmLabel.Tooltip = {'Enter the length, width, and height values for the bounding box'};
            app.CuboidDimmmLabel.Text = 'Cuboid Dim. (mm):';
            app.XSpinnerLabel_4 = uilabel(app.Panel);
            app.XSpinnerLabel_4.Text = 'X:';
            app.cuboidX = uispinner(app.Panel);
            app.cuboidX.Limits = [0 Inf];
            app.cuboidX.Value = 20;
            app.YLabel = uilabel(app.Panel);
            app.YLabel.Text = 'Y:';
            app.cuboidY = uispinner(app.Panel);
            app.cuboidY.Limits = [0 Inf];
            app.cuboidY.Value = 20;
            app.ZLabel = uilabel(app.Panel);
            app.ZLabel.Text = 'Z:';
            app.CellDimmmLabel = uilabel(app.Panel);
            app.CellDimmmLabel.Tooltip = {'Enter the length, width, and height values for the unit cell'};
            app.CellDimmmLabel.Text = 'Cell Dim. (mm):';
            app.cuboidZ = uispinner(app.Panel);
            app.cuboidZ.Limits = [0 Inf];
            app.cuboidZ.Value = 20;
            app.XSpinnerLabel_5 = uilabel(app.Panel);
            app.XSpinnerLabel_5.Text = 'X:';
            app.cellX = uispinner(app.Panel);
            app.cellX.Limits = [0 Inf];
            app.cellX.Value = 10;
            app.YLabel_2 = uilabel(app.Panel);
            app.YLabel_2.Text = 'Y:';
            app.cellY = uispinner(app.Panel);
            app.cellY.Limits = [0 Inf];
            app.cellY.Value = 10;
            app.ZLabel_2 = uilabel(app.Panel);
            app.ZLabel_2.Text = 'Z:';
            app.cellZ = uispinner(app.Panel);
            app.cellZ.Limits = [0 Inf];
            app.cellZ.Value = 10;
            app.ImportCustomDomainLabel_2 = uilabel(app.Panel);
            app.ImportCustomDomainLabel_2.Tooltip = {''};
            app.ImportCustomDomainLabel_2.Text = 'Sandwich Structure:';
            app.CompressiveSample = uidropdown(app.Panel);
            app.CompressiveSample.Items = {'No', 'Yes'};
            app.CompressiveSample.Value = 'No';
            app.ZLabel_20 = uilabel(app.Panel);
            app.ZLabel_20.Tooltip = {'Height of the top and bottom plates for the structure'};
            app.ZLabel_20.Text = 'Height:';
            app.height = uispinner(app.Panel);
            app.height.Limits = [0 Inf];
            app.height.Value = 1;
            app.Panel_2 = uipanel(app.StrutandPlatetypeLatticeGeneratorTab);
            app.UnitCellDesignLabel = uilabel(app.Panel_2);
            app.UnitCellDesignLabel.Text = 'Unit Cell Design';
            app.FunctionLabel = uilabel(app.Panel_2);
            app.FunctionLabel.Tooltip = {'Select a predefined cell topology or select "Custom" for custom strut cells'};
            app.FunctionLabel.Text = 'Cell Topology:';
            app.Topology = uidropdown(app.Panel_2);
            app.Topology.Items = {'BCC', 'BCC-Curved', 'Octet', 'FCC', 'FCC-Curved', 'DCC-Curved', 'Cubic', 'Octagon', 'Kelvin Cell', 'Butterfly-Curved', 'X-rods', 'Y-rods', 'Z-rods', 'Octet-Plate', 'Cross-Plate', 'Diamond-Plate', 'X-Plate', 'Box-PlateX', 'Box-PlateY', 'Box-PlateZ', 'Custom'};
            app.Topology.ValueChangedFcn = createCallbackFcn(app, @TopologyValueChanged, true);
            app.Topology.Tooltip = {''};
            app.Topology.Value = 'BCC';
            app.CustomStrutCellsLabel = uilabel(app.Panel_2);
            app.CustomStrutCellsLabel.Text = 'Custom Strut Cells:';
            app.XSpinnerLabel_10 = uilabel(app.Panel_2);
            app.XSpinnerLabel_10.Text = 'X:';
            app.StartPointX = uispinner(app.Panel_2);
            app.StartPointX.Step = 0.1;
            app.StartPointX.Limits = [-1 1];
            app.StartPointX.Enable = 'off';
            app.StartPointX.Value = -1;
            app.StartPointmmLabel = uilabel(app.Panel_2);
            app.StartPointmmLabel.Tooltip = {'First end of the desired strut'};
            app.StartPointmmLabel.Text = 'Start Point (mm):';
            app.EndPointmmLabel = uilabel(app.Panel_2);
            app.EndPointmmLabel.Tooltip = {'Second end of the desired strut'};
            app.EndPointmmLabel.Text = 'End Point (mm):';
            app.YLabel_13 = uilabel(app.Panel_2);
            app.YLabel_13.Text = 'Y:';
            app.StartPointY = uispinner(app.Panel_2);
            app.StartPointY.Step = 0.1;
            app.StartPointY.Limits = [-1 1];
            app.StartPointY.Enable = 'off';
            app.StartPointY.Value = -1;
            app.ZLabel_15 = uilabel(app.Panel_2);
            app.ZLabel_15.Text = 'Z:';
            app.StartPointZ = uispinner(app.Panel_2);
            app.StartPointZ.Step = 0.1;
            app.StartPointZ.Limits = [-1 1];
            app.StartPointZ.Enable = 'off';
            app.StartPointZ.Value = -1;
            app.XSpinnerLabel_11 = uilabel(app.Panel_2);
            app.XSpinnerLabel_11.Text = 'X:';
            app.EndPointX = uispinner(app.Panel_2);
            app.EndPointX.Step = 0.1;
            app.EndPointX.Limits = [-1 1];
            app.EndPointX.Enable = 'off';
            app.EndPointX.Value = 1;
            app.YLabel_14 = uilabel(app.Panel_2);
            app.YLabel_14.Text = 'Y:';
            app.EndPointY = uispinner(app.Panel_2);
            app.EndPointY.Step = 0.1;
            app.EndPointY.Limits = [-1 1];
            app.EndPointY.Enable = 'off';
            app.EndPointY.Value = 1;
            app.ZLabel_16 = uilabel(app.Panel_2);
            app.ZLabel_16.Text = 'Z:';
            app.EndPointZ = uispinner(app.Panel_2);
            app.EndPointZ.Step = 0.1;
            app.EndPointZ.Limits = [-1 1];
            app.EndPointZ.Enable = 'off';
            app.EndPointZ.Value = 1;
            app.AddStrut = uibutton(app.Panel_2, 'state');
            app.AddStrut.ValueChangedFcn = createCallbackFcn(app, @AddStrutValueChanged, true);
            app.AddStrut.Enable = 'off';
            app.AddStrut.Tooltip = {'Add the start and end points of the next strut to the dataset'};
            app.AddStrut.Text = 'Add ';
            app.HybridStructure = uiswitch(app.Panel_2, 'slider');
            app.HybridStructure.Items = {'Single', 'Combined'};
            app.HybridStructure.ValueChangedFcn = createCallbackFcn(app, @HybridStructureValueChanged, true);
            app.HybridStructure.Tooltip = {'Single unit cell topology or combined cell topologies'};
            app.HybridStructure.Value = 'Single';
            app.CellTopology2Label = uilabel(app.Panel_2);
            app.CellTopology2Label.Tooltip = {'Select a predefined topology to be combined from the dropdown'};
            app.CellTopology2Label.Text = 'Cell Topology 2:';
            app.Topology2 = uidropdown(app.Panel_2);
            app.Topology2.Items = {'BCC', 'BCC-Curved', 'Octet', 'FCC', 'FCC-Curved', 'DCC-Curved', 'Cubic', 'Octagon', 'Kelvin Cell', 'Butterfly-Curved', 'X-rods', 'Y-rods', 'Z-rods', 'Octet-Plate', 'Cross-Plate', 'Diamond-Plate', 'X-Plate', 'Box-PlateX', 'Box-PlateY', 'Box-PlateZ'};
            app.Topology2.ValueChangedFcn = createCallbackFcn(app, @Topology2ValueChanged, true);
            app.Topology2.Enable = 'off';
            app.Topology2.Tooltip = {''};
            app.Topology2.Value = 'BCC';
            app.NumberOfStruts = uieditfield(app.Panel_2, 'text');
            app.NumberOfStruts.Editable = 'off';
            app.NumberOfStruts.Enable = 'off';
            app.NumberOfStruts.Tooltip = {'The number of struts in the dataset is displayed here'};
            app.NumberOfStruts.Value = '0';
            app.RemoveStrut = uibutton(app.Panel_2, 'state');
            app.RemoveStrut.ValueChangedFcn = createCallbackFcn(app, @RemoveStrutValueChanged, true);
            app.RemoveStrut.Enable = 'off';
            app.RemoveStrut.Tooltip = {'Remove the start and end points of the last strut to the dataset'};
            app.RemoveStrut.Text = 'Remove';
            app.Hollow = uiswitch(app.Panel_2, 'slider');
            app.Hollow.Items = {'Solid', 'Hollow'};
            app.Hollow.ValueChangedFcn = createCallbackFcn(app, @HollowValueChanged, true);
            app.Hollow.Tooltip = {'Solid or hollow struts'};
            app.Hollow.Value = 'Solid';
            app.ImportCustomCell = uieditfield(app.Panel_2, 'text');
            app.ImportCustomCell.Editable = 'off';
            app.ImportCustomCell.Enable = 'off';
            app.ImportCustomCell.Value = 'Import Custom Cell';
            app.CustomBrowseButton = uibutton(app.Panel_2, 'state');
            app.CustomBrowseButton.ValueChangedFcn = createCallbackFcn(app, @CustomBrowseButtonValueChanged, true);
            app.CustomBrowseButton.Enable = 'off';
            app.CustomBrowseButton.Tooltip = {'Import a .txt file containing end points for the desired custom cell'};
            app.CustomBrowseButton.Text = 'Browse';
            app.Panel_3 = uipanel(app.StrutandPlatetypeLatticeGeneratorTab);
            app.DensityControlLabel = uilabel(app.Panel_3);
            app.DensityControlLabel.Tooltip = {''};
            app.DensityControlLabel.Text = 'Density Control';
            app.ThicknessLabel = uilabel(app.Panel_3);
            app.ThicknessLabel.Tooltip = {'Set the desired thickness value'};
            app.ThicknessLabel.Text = 'Thickness (mm):';
            app.thickness = uispinner(app.Panel_3);
            app.thickness.Step = 0.1;
            app.thickness.Limits = [0.01 Inf];
            app.thickness.Tooltip = {''};
            app.thickness.Value = 1;
            app.CellRelativeDensityLabel = uilabel(app.Panel_3);
            app.CellRelativeDensityLabel.Tooltip = {'Set the desired relative density value to a number between 0 and 1'};
            app.CellRelativeDensityLabel.Text = 'Cell Relative Density:';
            app.relativeDensity = uispinner(app.Panel_3);
            app.relativeDensity.Step = 0.1;
            app.relativeDensity.Limits = [0.01 1];
            app.relativeDensity.ValueChangedFcn = createCallbackFcn(app, @relativeDensityValueChanged, true);
            app.relativeDensity.Enable = 'off';
            app.relativeDensity.Tooltip = {''};
            app.relativeDensity.Value = 0.01;
            app.Plot = uibutton(app.Panel_3, 'state');
            app.Plot.ValueChangedFcn = createCallbackFcn(app, @PlotValueChanged, true);
            app.Plot.Text = 'Generate';
            app.ExportSTL = uibutton(app.Panel_3, 'push');
            app.ExportSTL.ButtonPushedFcn = createCallbackFcn(app, @ExportSTLButtonPushed, true);
            app.ExportSTL.Text = 'Export STL';
            app.Lamp = uilamp(app.Panel_3);
            app.LampLabel = uilabel(app.Panel_3);
            app.LampLabel.Text = ' ';
            app.Stop = uibutton(app.Panel_3, 'state');
            app.Stop.ValueChangedFcn = createCallbackFcn(app, @StopValueChanged, true);
            app.Stop.Tooltip = {''};
            app.Stop.Text = 'Stop';
            app.EquationLabel = uilabel(app.Panel_3);
            app.EquationLabel.Tooltip = {'Set a grading pattern'};
            app.EquationLabel.Text = 'Grading Equation:';
            app.GradingEquation = uidropdown(app.Panel_3);
            app.GradingEquation.Items = {'Linear', 'Quadratic', 'Cubic', 'Circular', 'Exponential', 'Trigonometric - Sin', 'Trigonometric - Cosine'};
            app.GradingEquation.ValueChangedFcn = createCallbackFcn(app, @GradingEquationValueChanged, true);
            app.GradingEquation.Enable = 'off';
            app.GradingEquation.Tooltip = {''};
            app.GradingEquation.Value = 'Linear';
            app.GradingFormula = uieditfield(app.Panel_3, 'text');
            app.GradingFormula.Editable = 'off';
            app.GradingFormula.Enable = 'off';
            app.GradingFormula.Tooltip = {''};
            app.GradingFormula.Value = 'AX + BY + CZ + D';
            app.CoefficientLabel = uilabel(app.Panel_3);
            app.CoefficientLabel.Tooltip = {'Adjust the coefficient of the selected grading equation to control the grading pattern'};
            app.CoefficientLabel.Text = 'Coefficient:';
            app.RelativePosition_grade = uilabel(app.Panel_3);
            app.RelativePosition_grade.Tooltip = {'Define the starting position'};
            app.RelativePosition_grade.Text = 'Reference Position:';
            app.curve_control = uispinner(app.Panel_3);
            app.curve_control.Limits = [0 Inf];
            app.curve_control.Enable = 'off';
            app.curve_control.Tooltip = {''};
            app.curve_control.Value = 10;
            app.ThicknessLabel_3 = uilabel(app.Panel_3);
            app.ThicknessLabel_3.Tooltip = {'Set the desired curvature parameter for cell topology 1'};
            app.ThicknessLabel_3.Text = 'Curve Control:';
            app.ThicknessLabel_4 = uilabel(app.Panel_3);
            app.ThicknessLabel_4.Tooltip = {'Set the desired thickness value for grading'};
            app.ThicknessLabel_4.Text = 'Thickness 2 (mm):';
            app.thickness_grade_2 = uispinner(app.Panel_3);
            app.thickness_grade_2.Step = 0.1;
            app.thickness_grade_2.Limits = [0.01 Inf];
            app.thickness_grade_2.Enable = 'off';
            app.thickness_grade_2.Tooltip = {''};
            app.thickness_grade_2.Value = 2.5;
            app.RelativeDensity2Label = uilabel(app.Panel_3);
            app.RelativeDensity2Label.Tooltip = {'Set the desired relative density value between 0 and 1 for grading'};
            app.RelativeDensity2Label.Text = 'Cell Relative Density 2:';
            app.relativeDensity_grade_2 = uispinner(app.Panel_3);
            app.relativeDensity_grade_2.Step = 0.1;
            app.relativeDensity_grade_2.Limits = [0.01 1];
            app.relativeDensity_grade_2.ValueChangedFcn = createCallbackFcn(app, @relativeDensity_grade_2ValueChanged, true);
            app.relativeDensity_grade_2.Enable = 'off';
            app.relativeDensity_grade_2.Tooltip = {''};
            app.relativeDensity_grade_2.Value = 0.01;
            app.ALabel_5 = uilabel(app.Panel_3);
            app.ALabel_5.Tooltip = {''};
            app.ALabel_5.Text = 'A:';
            app.coefficientA_grade = uispinner(app.Panel_3);
            app.coefficientA_grade.Enable = 'off';
            app.coefficientA_grade.Tooltip = {''};
            app.coefficientA_grade.Value = 1;
            app.YLabel_15 = uilabel(app.Panel_3);
            app.YLabel_15.Tooltip = {''};
            app.YLabel_15.Text = 'B:';
            app.coefficientB_grade = uispinner(app.Panel_3);
            app.coefficientB_grade.Enable = 'off';
            app.coefficientB_grade.Tooltip = {''};
            app.ZLabel_17 = uilabel(app.Panel_3);
            app.ZLabel_17.Tooltip = {''};
            app.ZLabel_17.Text = 'C:';
            app.coefficientC_grade = uispinner(app.Panel_3);
            app.coefficientC_grade.Enable = 'off';
            app.coefficientC_grade.Tooltip = {''};
            app.ALabel_6 = uilabel(app.Panel_3);
            app.ALabel_6.Tooltip = {''};
            app.ALabel_6.Text = 'X:';
            app.positionX_grade = uispinner(app.Panel_3);
            app.positionX_grade.Enable = 'off';
            app.positionX_grade.Tooltip = {''};
            app.YLabel_16 = uilabel(app.Panel_3);
            app.YLabel_16.Tooltip = {''};
            app.YLabel_16.Text = 'Y:';
            app.positionY_grade = uispinner(app.Panel_3);
            app.positionY_grade.Enable = 'off';
            app.positionY_grade.Tooltip = {''};
            app.ZLabel_18 = uilabel(app.Panel_3);
            app.ZLabel_18.Tooltip = {''};
            app.ZLabel_18.Text = 'Z:';
            app.positionZ_grade = uispinner(app.Panel_3);
            app.positionZ_grade.Enable = 'off';
            app.positionZ_grade.Tooltip = {''};
            app.DensityType = uiswitch(app.Panel_3, 'slider');
            app.DensityType.Items = {'Uniform', 'Graded'};
            app.DensityType.ValueChangedFcn = createCallbackFcn(app, @DensityTypeValueChanged, true);
            app.DensityType.Tooltip = {'Toggle between uniform and field-driven gradient density control'};
            app.DensityType.Value = 'Uniform';
            app.ZLabel_19 = uilabel(app.Panel_3);
            app.ZLabel_19.Tooltip = {''};
            app.ZLabel_19.Text = 'D:';
            app.coefficientD_grade = uispinner(app.Panel_3);
            app.coefficientD_grade.Enable = 'off';
            app.coefficientD_grade.Tooltip = {''};
            app.DensityControl = uiswitch(app.Panel_3, 'slider');
            app.DensityControl.Items = {'Thickness', 'Relative Density'};
            app.DensityControl.ValueChangedFcn = createCallbackFcn(app, @DensityControlValueChanged, true);
            app.DensityControl.Tooltip = {'Toggle between thickness and relative density-based density controlled '};
            app.DensityControl.Value = 'Thickness';
            app.ThicknessLabel_5 = uilabel(app.Panel_3);
            app.ThicknessLabel_5.Tooltip = {'Set the desired thickness value for hollow struts'};
            app.ThicknessLabel_5.Text = 'Inner-Outer-Dia (mm):';
            app.InnerDiameter = uispinner(app.Panel_3);
            app.InnerDiameter.Step = 0.1;
            app.InnerDiameter.Limits = [0.01 Inf];
            app.InnerDiameter.Enable = 'off';
            app.InnerDiameter.Tooltip = {''};
            app.InnerDiameter.Value = 1;
            app.OuterDiameter = uispinner(app.Panel_3);
            app.OuterDiameter.Step = 0.1;
            app.OuterDiameter.Limits = [0.01 Inf];
            app.OuterDiameter.Enable = 'off';
            app.OuterDiameter.Tooltip = {''};
            app.OuterDiameter.Value = 2.5;
            app.ThicknessLabel_6 = uilabel(app.Panel_3);
            app.ThicknessLabel_6.Tooltip = {'Set the desired thickness value for grading of hollow struts'};
            app.ThicknessLabel_6.Text = 'Inner-Outer-Dia 2 (mm):';
            app.InnerDiameter2 = uispinner(app.Panel_3);
            app.InnerDiameter2.Step = 0.1;
            app.InnerDiameter2.Limits = [0.01 Inf];
            app.InnerDiameter2.Enable = 'off';
            app.InnerDiameter2.Tooltip = {''};
            app.InnerDiameter2.Value = 1.5;
            app.OuterDiameter2 = uispinner(app.Panel_3);
            app.OuterDiameter2.Step = 0.1;
            app.OuterDiameter2.Limits = [0.01 Inf];
            app.OuterDiameter2.Enable = 'off';
            app.OuterDiameter2.Tooltip = {''};
            app.OuterDiameter2.Value = 2.5;
            app.SaveCustom = uibutton(app.Panel_3, 'push');
            app.SaveCustom.ButtonPushedFcn = createCallbackFcn(app, @SaveCustomButtonPushed, true);
            app.SaveCustom.Enable = 'off';
            app.SaveCustom.Text = 'Save Custom';
            app.ZLabel_21 = uilabel(app.Panel_3);
            app.ZLabel_21.Tooltip = {''};
            app.ZLabel_21.Text = 'R:';
            app.radius_parameter = uispinner(app.Panel_3);
            app.radius_parameter.Enable = 'off';
            app.radius_parameter.Tooltip = {''};
            app.curve_control_2 = uispinner(app.Panel_3);
            app.curve_control_2.Limits = [0 Inf];
            app.curve_control_2.Enable = 'off';
            app.curve_control_2.Tooltip = {''};
            app.curve_control_2.Value = 10;
            app.ThicknessLabel_7 = uilabel(app.Panel_3);
            app.ThicknessLabel_7.Tooltip = {'Set the desired curvature parameter for cell topology 2'};
            app.ThicknessLabel_7.Text = 'Curve Control 2:';
            app.Panel_12 = uipanel(app.StrutandPlatetypeLatticeGeneratorTab);
            app.NumberofGridpointsSpinnerLabel_4 = uilabel(app.Panel_12);
            app.NumberofGridpointsSpinnerLabel_4.Tooltip = {'Specify the number of gridpoints for generating the plot'};
            app.NumberofGridpointsSpinnerLabel_4.Text = 'Number of Gridpoints:';
            app.Gridpoints = uispinner(app.Panel_12);
            app.Gridpoints.Limits = [0 Inf];
            app.Gridpoints.RoundFractionalValues = 'on';
            app.Gridpoints.Value = 30;
            app.CurvepointsLabel = uilabel(app.Panel_12);
            app.CurvepointsLabel.Tooltip = {'Specify the number of line segments for defining the curvature'};
            app.CurvepointsLabel.Text = 'Number of Curvepoints:';
            app.Curvepoints = uispinner(app.Panel_12);
            app.Curvepoints.Enable = 'off';
            app.Curvepoints.Value = 20;
            app.ResolutionControlLabel = uilabel(app.Panel_12);
            app.ResolutionControlLabel.Text = 'Resolution Control';
            app.Panel_13 = uipanel(app.StrutandPlatetypeLatticeGeneratorTab);
            app.ExactRelativeDensityLabel_4 = uilabel(app.Panel_13);
            app.ExactRelativeDensityLabel_4.Tooltip = {'The volume of the lattice structure (in cubic centimetre) is displayed here'};
            app.ExactRelativeDensityLabel_4.Text = 'Volume (cm^3):';
            app.LatticeVolume = uieditfield(app.Panel_13, 'text');
            app.LatticeVolume.Editable = 'off';
            app.LatticeVolume.Enable = 'off';
            app.LatticeVolume.Tooltip = {''};
            app.ExactRelativeDensityLabel_5 = uilabel(app.Panel_13);
            app.ExactRelativeDensityLabel_5.Tooltip = {'The number of nodes in the 3D grid is displayed here'};
            app.ExactRelativeDensityLabel_5.Text = 'Number of Nodes: ';
            app.Nodes = uieditfield(app.Panel_13, 'text');
            app.Nodes.Editable = 'off';
            app.Nodes.Enable = 'off';
            app.Nodes.Tooltip = {''};
            app.ExactRelativeDensityLabel_6 = uilabel(app.Panel_13);
            app.ExactRelativeDensityLabel_6.Tooltip = {'The generation runtime (in seconds) is displayed here'};
            app.ExactRelativeDensityLabel_6.Text = 'Runtime (secs):';
            app.Runtime = uieditfield(app.Panel_13, 'text');
            app.Runtime.Editable = 'off';
            app.Runtime.Enable = 'off';
            app.Runtime.Tooltip = {''};
            app.ExactRelativeDensityLabel_7 = uilabel(app.Panel_13);
            app.ExactRelativeDensityLabel_7.Tooltip = {'The exact relative density of the structure is displayed here'};
            app.ExactRelativeDensityLabel_7.Text = 'Exact Relative Density';
            app.exactRelativeDensity = uieditfield(app.Panel_13, 'text');
            app.exactRelativeDensity.Editable = 'off';
            app.exactRelativeDensity.Enable = 'off';
            app.exactRelativeDensity.Tooltip = {''};
            app.SummaryLabel_3 = uilabel(app.Panel_13);
            app.SummaryLabel_3.Tooltip = {''};
            app.SummaryLabel_3.Text = 'Summary';
            app.HomogenizationTab = uitab(app.TabGroup);
            app.HomogenizationTab.Title = 'Homogenization';
            app.UIAxes_2 = uiaxes(app.HomogenizationTab);
            xlabel(app.UIAxes_2, 'X (Pa)')
            ylabel(app.UIAxes_2, 'Y (Pa)')
            zlabel(app.UIAxes_2, 'Z (Pa)')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.Panel_18 = uipanel(app.HomogenizationTab);
            app.ConstitutiveMatrixLabel = uilabel(app.Panel_18);
            app.ConstitutiveMatrixLabel.Tooltip = {'The generation runtime (in seconds) is displayed here'};
            app.ConstitutiveMatrixLabel.Text = 'Constitutive Matrix: ';
            app.CM31 = uieditfield(app.Panel_18, 'text');
            app.CM31.Editable = 'off';
            app.CM31.Tooltip = {''};
            app.CM32 = uieditfield(app.Panel_18, 'text');
            app.CM32.Editable = 'off';
            app.CM32.Tooltip = {''};
            app.CM33 = uieditfield(app.Panel_18, 'text');
            app.CM33.Editable = 'off';
            app.CM33.Tooltip = {''};
            app.CM34 = uieditfield(app.Panel_18, 'text');
            app.CM34.Editable = 'off';
            app.CM34.Tooltip = {''};
            app.CM35 = uieditfield(app.Panel_18, 'text');
            app.CM35.Editable = 'off';
            app.CM35.Tooltip = {''};
            app.CM36 = uieditfield(app.Panel_18, 'text');
            app.CM36.Editable = 'off';
            app.CM36.Tooltip = {''};
            app.CM30 = uieditfield(app.Panel_18, 'text');
            app.CM30.Editable = 'off';
            app.CM30.Tooltip = {''};
            app.CM29 = uieditfield(app.Panel_18, 'text');
            app.CM29.Editable = 'off';
            app.CM29.Tooltip = {''};
            app.CM28 = uieditfield(app.Panel_18, 'text');
            app.CM28.Editable = 'off';
            app.CM28.Tooltip = {''};
            app.CM27 = uieditfield(app.Panel_18, 'text');
            app.CM27.Editable = 'off';
            app.CM27.Tooltip = {''};
            app.CM26 = uieditfield(app.Panel_18, 'text');
            app.CM26.Editable = 'off';
            app.CM26.Tooltip = {''};
            app.CM25 = uieditfield(app.Panel_18, 'text');
            app.CM25.Editable = 'off';
            app.CM25.Tooltip = {''};
            app.CM24 = uieditfield(app.Panel_18, 'text');
            app.CM24.Editable = 'off';
            app.CM24.Tooltip = {''};
            app.CM23 = uieditfield(app.Panel_18, 'text');
            app.CM23.Editable = 'off';
            app.CM23.Tooltip = {''};
            app.CM22 = uieditfield(app.Panel_18, 'text');
            app.CM22.Editable = 'off';
            app.CM22.Tooltip = {''};
            app.CM21 = uieditfield(app.Panel_18, 'text');
            app.CM21.Editable = 'off';
            app.CM21.Tooltip = {''};
            app.CM20 = uieditfield(app.Panel_18, 'text');
            app.CM20.Editable = 'off';
            app.CM20.Tooltip = {''};
            app.CM19 = uieditfield(app.Panel_18, 'text');
            app.CM19.Editable = 'off';
            app.CM19.Tooltip = {''};
            app.CM18 = uieditfield(app.Panel_18, 'text');
            app.CM18.Editable = 'off';
            app.CM18.Tooltip = {''};
            app.CM17 = uieditfield(app.Panel_18, 'text');
            app.CM17.Editable = 'off';
            app.CM17.Tooltip = {''};
            app.CM16 = uieditfield(app.Panel_18, 'text');
            app.CM16.Editable = 'off';
            app.CM16.Tooltip = {''};
            app.CM15 = uieditfield(app.Panel_18, 'text');
            app.CM15.Editable = 'off';
            app.CM15.Tooltip = {''};
            app.CM14 = uieditfield(app.Panel_18, 'text');
            app.CM14.Editable = 'off';
            app.CM14.Tooltip = {''};
            app.CM13 = uieditfield(app.Panel_18, 'text');
            app.CM13.Editable = 'off';
            app.CM13.Tooltip = {''};
            app.CM12 = uieditfield(app.Panel_18, 'text');
            app.CM12.Editable = 'off';
            app.CM12.Tooltip = {''};
            app.CM11 = uieditfield(app.Panel_18, 'text');
            app.CM11.Editable = 'off';
            app.CM11.Tooltip = {''};
            app.CM10 = uieditfield(app.Panel_18, 'text');
            app.CM10.Editable = 'off';
            app.CM10.Tooltip = {''};
            app.CM9 = uieditfield(app.Panel_18, 'text');
            app.CM9.Editable = 'off';
            app.CM9.Tooltip = {''};
            app.CM8 = uieditfield(app.Panel_18, 'text');
            app.CM8.Editable = 'off';
            app.CM8.Tooltip = {''};
            app.CM7 = uieditfield(app.Panel_18, 'text');
            app.CM7.Editable = 'off';
            app.CM7.Tooltip = {''};
            app.CM6 = uieditfield(app.Panel_18, 'text');
            app.CM6.Editable = 'off';
            app.CM6.Tooltip = {''};
            app.CM5 = uieditfield(app.Panel_18, 'text');
            app.CM5.Editable = 'off';
            app.CM5.Tooltip = {''};
            app.CM4 = uieditfield(app.Panel_18, 'text');
            app.CM4.Editable = 'off';
            app.CM4.Tooltip = {''};
            app.CM3 = uieditfield(app.Panel_18, 'text');
            app.CM3.Editable = 'off';
            app.CM3.Tooltip = {''};
            app.CM2 = uieditfield(app.Panel_18, 'text');
            app.CM2.Editable = 'off';
            app.CM2.Tooltip = {''};
            app.CM1 = uieditfield(app.Panel_18, 'text');
            app.CM1.Editable = 'off';
            app.CM1.Tooltip = {''};
            app.Panel_15 = uipanel(app.HomogenizationTab);
            app.UnitCellDesignLabel_2 = uilabel(app.Panel_15);
            app.UnitCellDesignLabel_2.Text = 'Unit Cell Design';
            app.FunctionLabel_2 = uilabel(app.Panel_15);
            app.FunctionLabel_2.Tooltip = {'Select a predefined cell topology or select "Custom" for custom strut cells'};
            app.FunctionLabel_2.Text = 'Cell Topology:';
            app.Topology_2 = uidropdown(app.Panel_15);
            app.Topology_2.Items = {'BCC', 'BCC-Curved', 'Octet', 'FCC', 'FCC-Curved', 'DCC-Curved', 'Cubic', 'Octagon', 'Kelvin Cell', 'Butterfly-Curved', 'X-rods', 'Y-rods', 'Z-rods', 'Octet-Plate', 'Cross-Plate', 'Diamond-Plate', 'X-Plate', 'Box-PlateX', 'Box-PlateY', 'Box-PlateZ', 'Custom'};
            app.Topology_2.ValueChangedFcn = createCallbackFcn(app, @Topology_2ValueChanged, true);
            app.Topology_2.Tooltip = {''};
            app.Topology_2.Value = 'BCC';
            app.CustomStrutCellsLabel_2 = uilabel(app.Panel_15);
            app.CustomStrutCellsLabel_2.Text = 'Custom Strut Cells:';
            app.XSpinnerLabel_14 = uilabel(app.Panel_15);
            app.XSpinnerLabel_14.Text = 'X:';
            app.StartPointX_2 = uispinner(app.Panel_15);
            app.StartPointX_2.Step = 0.1;
            app.StartPointX_2.Limits = [-1 1];
            app.StartPointX_2.Enable = 'off';
            app.StartPointX_2.Value = -1;
            app.StartPointmmLabel_2 = uilabel(app.Panel_15);
            app.StartPointmmLabel_2.Tooltip = {'First end of the desired strut'};
            app.StartPointmmLabel_2.Text = 'Start Point (mm):';
            app.EndPointmmLabel_2 = uilabel(app.Panel_15);
            app.EndPointmmLabel_2.Tooltip = {'Second end of the desired strut'};
            app.EndPointmmLabel_2.Text = 'End Point (mm):';
            app.YLabel_19 = uilabel(app.Panel_15);
            app.YLabel_19.Text = 'Y:';
            app.StartPointY_2 = uispinner(app.Panel_15);
            app.StartPointY_2.Step = 0.1;
            app.StartPointY_2.Limits = [-1 1];
            app.StartPointY_2.Enable = 'off';
            app.StartPointY_2.Value = -1;
            app.ZLabel_25 = uilabel(app.Panel_15);
            app.ZLabel_25.Text = 'Z:';
            app.StartPointZ_2 = uispinner(app.Panel_15);
            app.StartPointZ_2.Step = 0.1;
            app.StartPointZ_2.Limits = [-1 1];
            app.StartPointZ_2.Enable = 'off';
            app.StartPointZ_2.Value = -1;
            app.XSpinnerLabel_15 = uilabel(app.Panel_15);
            app.XSpinnerLabel_15.Text = 'X:';
            app.EndPointX_2 = uispinner(app.Panel_15);
            app.EndPointX_2.Step = 0.1;
            app.EndPointX_2.Limits = [-1 1];
            app.EndPointX_2.Enable = 'off';
            app.EndPointX_2.Value = 1;
            app.YLabel_20 = uilabel(app.Panel_15);
            app.YLabel_20.Text = 'Y:';
            app.EndPointY_2 = uispinner(app.Panel_15);
            app.EndPointY_2.Step = 0.1;
            app.EndPointY_2.Limits = [-1 1];
            app.EndPointY_2.Enable = 'off';
            app.EndPointY_2.Value = 1;
            app.ZLabel_26 = uilabel(app.Panel_15);
            app.ZLabel_26.Text = 'Z:';
            app.EndPointZ_2 = uispinner(app.Panel_15);
            app.EndPointZ_2.Step = 0.1;
            app.EndPointZ_2.Limits = [-1 1];
            app.EndPointZ_2.Enable = 'off';
            app.EndPointZ_2.Value = 1;
            app.AddStrut_2 = uibutton(app.Panel_15, 'state');
            app.AddStrut_2.ValueChangedFcn = createCallbackFcn(app, @AddStrut_2ValueChanged, true);
            app.AddStrut_2.Enable = 'off';
            app.AddStrut_2.Tooltip = {'Add the start and end points of the next strut to the dataset'};
            app.AddStrut_2.Text = 'Add ';
            app.HybridStructure_2 = uiswitch(app.Panel_15, 'slider');
            app.HybridStructure_2.Items = {'Single', 'Combined'};
            app.HybridStructure_2.ValueChangedFcn = createCallbackFcn(app, @HybridStructure_2ValueChanged, true);
            app.HybridStructure_2.Tooltip = {'Single unit cell topology or combined cell topologies'};
            app.HybridStructure_2.Value = 'Single';
            app.CellTopology2Label_2 = uilabel(app.Panel_15);
            app.CellTopology2Label_2.Tooltip = {'Select a predefined topology to be combined from the dropdown'};
            app.CellTopology2Label_2.Text = 'Cell Topology 2:';
            app.Topology2_2 = uidropdown(app.Panel_15);
            app.Topology2_2.Items = {'BCC', 'BCC-Curved', 'Octet', 'FCC', 'FCC-Curved', 'DCC-Curved', 'Cubic', 'Octagon', 'Kelvin Cell', 'Butterfly-Curved', 'X-rods', 'Y-rods', 'Z-rods', 'Octet-Plate', 'Cross-Plate', 'Diamond-Plate', 'X-Plate', 'Box-PlateX', 'Box-PlateY', 'Box-PlateZ'};
            app.Topology2_2.ValueChangedFcn = createCallbackFcn(app, @Topology2_2ValueChanged, true);
            app.Topology2_2.Enable = 'off';
            app.Topology2_2.Tooltip = {''};
            app.Topology2_2.Value = 'BCC';
            app.NumberOfStruts_2 = uieditfield(app.Panel_15, 'text');
            app.NumberOfStruts_2.Editable = 'off';
            app.NumberOfStruts_2.Enable = 'off';
            app.NumberOfStruts_2.Tooltip = {'The number of struts in the dataset is displayed here'};
            app.NumberOfStruts_2.Value = '0';
            app.RemoveStrut_2 = uibutton(app.Panel_15, 'state');
            app.RemoveStrut_2.ValueChangedFcn = createCallbackFcn(app, @RemoveStrut_2ValueChanged, true);
            app.RemoveStrut_2.Enable = 'off';
            app.RemoveStrut_2.Tooltip = {'Remove the start and end points of the last strut to the dataset'};
            app.RemoveStrut_2.Text = 'Remove';
            app.Hollow_2 = uiswitch(app.Panel_15, 'slider');
            app.Hollow_2.Items = {'Solid', 'Hollow'};
            app.Hollow_2.ValueChangedFcn = createCallbackFcn(app, @Hollow_2ValueChanged, true);
            app.Hollow_2.Tooltip = {'Solid or hollow struts'};
            app.Hollow_2.Value = 'Solid';
            app.ImportCustomCell_2 = uieditfield(app.Panel_15, 'text');
            app.ImportCustomCell_2.Editable = 'off';
            app.ImportCustomCell_2.Enable = 'off';
            app.ImportCustomCell_2.Value = 'Import Custom Cell';
            app.CustomBrowseButton_2 = uibutton(app.Panel_15, 'state');
            app.CustomBrowseButton_2.ValueChangedFcn = createCallbackFcn(app, @CustomBrowseButton_2ValueChanged, true);
            app.CustomBrowseButton_2.Enable = 'off';
            app.CustomBrowseButton_2.Tooltip = {'Import a .txt file containing end points for the desired custom cell'};
            app.CustomBrowseButton_2.Text = 'Browse';
            app.CellDimmmLabel_2 = uilabel(app.Panel_15);
            app.CellDimmmLabel_2.Tooltip = {'Enter the length, width, and height values for the unit cell'};
            app.CellDimmmLabel_2.Text = 'Cell Dim. (mm):';
            app.XSpinnerLabel_16 = uilabel(app.Panel_15);
            app.XSpinnerLabel_16.Text = 'X:';
            app.cellX_2 = uispinner(app.Panel_15);
            app.cellX_2.Limits = [0 Inf];
            app.cellX_2.Value = 10;
            app.YLabel_23 = uilabel(app.Panel_15);
            app.YLabel_23.Text = 'Y:';
            app.cellY_2 = uispinner(app.Panel_15);
            app.cellY_2.Limits = [0 Inf];
            app.cellY_2.Value = 10;
            app.ZLabel_31 = uilabel(app.Panel_15);
            app.ZLabel_31.Text = 'Z:';
            app.cellZ_2 = uispinner(app.Panel_15);
            app.cellZ_2.Limits = [0 Inf];
            app.cellZ_2.Value = 10;
            app.Panel_16 = uipanel(app.HomogenizationTab);
            app.DensityControlLabel_2 = uilabel(app.Panel_16);
            app.DensityControlLabel_2.Tooltip = {''};
            app.DensityControlLabel_2.Text = 'Density Control';
            app.ThicknessLabel_8 = uilabel(app.Panel_16);
            app.ThicknessLabel_8.Tooltip = {'Set the desired thickness value'};
            app.ThicknessLabel_8.Text = 'Thickness (mm):';
            app.thickness_2 = uispinner(app.Panel_16);
            app.thickness_2.Step = 0.1;
            app.thickness_2.Limits = [0.01 Inf];
            app.thickness_2.Tooltip = {''};
            app.thickness_2.Value = 1;
            app.CellRelativeDensityLabel_2 = uilabel(app.Panel_16);
            app.CellRelativeDensityLabel_2.Tooltip = {'Set the desired relative density value to a number between 0 and 1'};
            app.CellRelativeDensityLabel_2.Text = 'Cell Relative Density:';
            app.relativeDensity_2 = uispinner(app.Panel_16);
            app.relativeDensity_2.Step = 0.1;
            app.relativeDensity_2.Limits = [0.01 1];
            app.relativeDensity_2.ValueChangedFcn = createCallbackFcn(app, @relativeDensity_2ValueChanged, true);
            app.relativeDensity_2.Enable = 'off';
            app.relativeDensity_2.Tooltip = {''};
            app.relativeDensity_2.Value = 0.01;
            app.RunHomogenization = uibutton(app.Panel_16, 'state');
            app.RunHomogenization.ValueChangedFcn = createCallbackFcn(app, @RunHomogenizationValueChanged, true);
            app.RunHomogenization.Text = 'Run Homogenization';
            app.ExportProperties = uibutton(app.Panel_16, 'push');
            app.ExportProperties.ButtonPushedFcn = createCallbackFcn(app, @ExportPropertiesButtonPushed, true);
            app.ExportProperties.Text = 'Export Properties';
            app.Lamp_2 = uilamp(app.Panel_16);
            app.LampLabel_2 = uilabel(app.Panel_16);
            app.LampLabel_2.Text = ' ';
            app.Stop_2 = uibutton(app.Panel_16, 'state');
            app.Stop_2.Tooltip = {''};
            app.Stop_2.Text = 'Stop';
            app.EquationLabel_2 = uilabel(app.Panel_16);
            app.EquationLabel_2.Tooltip = {'Set a grading pattern'};
            app.EquationLabel_2.Text = 'Grading Equation:';
            app.GradingEquation_2 = uidropdown(app.Panel_16);
            app.GradingEquation_2.Items = {'Linear', 'Quadratic', 'Cubic', 'Circular', 'Exponential', 'Trigonometric - Sin', 'Trigonometric - Cosine'};
            app.GradingEquation_2.ValueChangedFcn = createCallbackFcn(app, @GradingEquation_2ValueChanged, true);
            app.GradingEquation_2.Enable = 'off';
            app.GradingEquation_2.Tooltip = {''};
            app.GradingEquation_2.Value = 'Linear';
            app.GradingFormula_2 = uieditfield(app.Panel_16, 'text');
            app.GradingFormula_2.Editable = 'off';
            app.GradingFormula_2.Enable = 'off';
            app.GradingFormula_2.Tooltip = {''};
            app.GradingFormula_2.Value = 'AX + BY + CZ + D';
            app.CoefficientLabel_2 = uilabel(app.Panel_16);
            app.CoefficientLabel_2.Tooltip = {'Adjust the coefficient of the selected grading equation to control the grading pattern'};
            app.CoefficientLabel_2.Text = 'Coefficient:';
            app.RelativePosition_grade_2 = uilabel(app.Panel_16);
            app.RelativePosition_grade_2.Tooltip = {'Define the starting position'};
            app.RelativePosition_grade_2.Text = 'Reference Position:';
            app.curve_control_3 = uispinner(app.Panel_16);
            app.curve_control_3.Limits = [0 Inf];
            app.curve_control_3.Enable = 'off';
            app.curve_control_3.Tooltip = {''};
            app.curve_control_3.Value = 10;
            app.ThicknessLabel_9 = uilabel(app.Panel_16);
            app.ThicknessLabel_9.Tooltip = {'Set the desired curvature parameter for cell topology 1'};
            app.ThicknessLabel_9.Text = 'Curve Control:';
            app.ThicknessLabel_10 = uilabel(app.Panel_16);
            app.ThicknessLabel_10.Tooltip = {'Set the desired thickness value for grading'};
            app.ThicknessLabel_10.Text = 'Thickness 2 (mm):';
            app.thickness_grade_3 = uispinner(app.Panel_16);
            app.thickness_grade_3.Step = 0.1;
            app.thickness_grade_3.Limits = [0.01 Inf];
            app.thickness_grade_3.Enable = 'off';
            app.thickness_grade_3.Tooltip = {''};
            app.thickness_grade_3.Value = 2.5;
            app.RelativeDensity2Label_2 = uilabel(app.Panel_16);
            app.RelativeDensity2Label_2.Tooltip = {'Set the desired relative density value between 0 and 1 for grading'};
            app.RelativeDensity2Label_2.Text = 'Cell Relative Density 2:';
            app.relativeDensity_grade_3 = uispinner(app.Panel_16);
            app.relativeDensity_grade_3.Step = 0.1;
            app.relativeDensity_grade_3.Limits = [0.01 1];
            app.relativeDensity_grade_3.ValueChangedFcn = createCallbackFcn(app, @relativeDensity_grade_3ValueChanged, true);
            app.relativeDensity_grade_3.Enable = 'off';
            app.relativeDensity_grade_3.Tooltip = {''};
            app.relativeDensity_grade_3.Value = 0.01;
            app.ALabel_7 = uilabel(app.Panel_16);
            app.ALabel_7.Tooltip = {''};
            app.ALabel_7.Text = 'A:';
            app.coefficientA_grade_2 = uispinner(app.Panel_16);
            app.coefficientA_grade_2.Enable = 'off';
            app.coefficientA_grade_2.Tooltip = {''};
            app.coefficientA_grade_2.Value = 1;
            app.YLabel_21 = uilabel(app.Panel_16);
            app.YLabel_21.Tooltip = {''};
            app.YLabel_21.Text = 'B:';
            app.coefficientB_grade_2 = uispinner(app.Panel_16);
            app.coefficientB_grade_2.Enable = 'off';
            app.coefficientB_grade_2.Tooltip = {''};
            app.ZLabel_27 = uilabel(app.Panel_16);
            app.ZLabel_27.Tooltip = {''};
            app.ZLabel_27.Text = 'C:';
            app.coefficientC_grade_2 = uispinner(app.Panel_16);
            app.coefficientC_grade_2.Enable = 'off';
            app.coefficientC_grade_2.Tooltip = {''};
            app.ALabel_8 = uilabel(app.Panel_16);
            app.ALabel_8.Tooltip = {''};
            app.ALabel_8.Text = 'X:';
            app.positionX_grade_2 = uispinner(app.Panel_16);
            app.positionX_grade_2.Enable = 'off';
            app.positionX_grade_2.Tooltip = {''};
            app.YLabel_22 = uilabel(app.Panel_16);
            app.YLabel_22.Tooltip = {''};
            app.YLabel_22.Text = 'Y:';
            app.positionY_grade_2 = uispinner(app.Panel_16);
            app.positionY_grade_2.Enable = 'off';
            app.positionY_grade_2.Tooltip = {''};
            app.ZLabel_28 = uilabel(app.Panel_16);
            app.ZLabel_28.Tooltip = {''};
            app.ZLabel_28.Text = 'Z:';
            app.positionZ_grade_2 = uispinner(app.Panel_16);
            app.positionZ_grade_2.Enable = 'off';
            app.positionZ_grade_2.Tooltip = {''};
            app.DensityType_2 = uiswitch(app.Panel_16, 'slider');
            app.DensityType_2.Items = {'Uniform', 'Graded'};
            app.DensityType_2.Tooltip = {'Toggle between uniform and field-driven gradient density control'};
            app.DensityType_2.Value = 'Uniform';
            app.ZLabel_29 = uilabel(app.Panel_16);
            app.ZLabel_29.Tooltip = {''};
            app.ZLabel_29.Text = 'D:';
            app.coefficientD_grade_2 = uispinner(app.Panel_16);
            app.coefficientD_grade_2.Enable = 'off';
            app.coefficientD_grade_2.Tooltip = {''};
            app.DensityControl_2 = uiswitch(app.Panel_16, 'slider');
            app.DensityControl_2.Items = {'Thickness', 'Relative Density'};
            app.DensityControl_2.ValueChangedFcn = createCallbackFcn(app, @DensityControl_2ValueChanged, true);
            app.DensityControl_2.Tooltip = {'Toggle between thickness and relative density-based density controlled '};
            app.DensityControl_2.Value = 'Thickness';
            app.ThicknessLabel_11 = uilabel(app.Panel_16);
            app.ThicknessLabel_11.Tooltip = {'Set the desired thickness value for hollow struts'};
            app.ThicknessLabel_11.Text = 'Inner-Outer-Dia (mm):';
            app.InnerDiameter_2 = uispinner(app.Panel_16);
            app.InnerDiameter_2.Step = 0.1;
            app.InnerDiameter_2.Limits = [0.01 Inf];
            app.InnerDiameter_2.Enable = 'off';
            app.InnerDiameter_2.Tooltip = {''};
            app.InnerDiameter_2.Value = 1;
            app.OuterDiameter_2 = uispinner(app.Panel_16);
            app.OuterDiameter_2.Step = 0.1;
            app.OuterDiameter_2.Limits = [0.01 Inf];
            app.OuterDiameter_2.Enable = 'off';
            app.OuterDiameter_2.Tooltip = {''};
            app.OuterDiameter_2.Value = 2.5;
            app.ThicknessLabel_12 = uilabel(app.Panel_16);
            app.ThicknessLabel_12.Tooltip = {'Set the desired thickness value for grading of hollow struts'};
            app.ThicknessLabel_12.Text = 'Inner-Outer-Dia 2 (mm):';
            app.InnerDiameter2_2 = uispinner(app.Panel_16);
            app.InnerDiameter2_2.Step = 0.1;
            app.InnerDiameter2_2.Limits = [0.01 Inf];
            app.InnerDiameter2_2.Enable = 'off';
            app.InnerDiameter2_2.Tooltip = {''};
            app.InnerDiameter2_2.Value = 1.5;
            app.OuterDiameter2_2 = uispinner(app.Panel_16);
            app.OuterDiameter2_2.Step = 0.1;
            app.OuterDiameter2_2.Limits = [0.01 Inf];
            app.OuterDiameter2_2.Enable = 'off';
            app.OuterDiameter2_2.Tooltip = {''};
            app.OuterDiameter2_2.Value = 2.5;
            app.ZLabel_30 = uilabel(app.Panel_16);
            app.ZLabel_30.Tooltip = {''};
            app.ZLabel_30.Text = 'R:';
            app.radius_parameter_2 = uispinner(app.Panel_16);
            app.radius_parameter_2.Enable = 'off';
            app.radius_parameter_2.Tooltip = {''};
            app.curve_control_4 = uispinner(app.Panel_16);
            app.curve_control_4.Limits = [0 Inf];
            app.curve_control_4.Enable = 'off';
            app.curve_control_4.Tooltip = {''};
            app.curve_control_4.Value = 10;
            app.ThicknessLabel_13 = uilabel(app.Panel_16);
            app.ThicknessLabel_13.Tooltip = {'Set the desired curvature parameter for cell topology 2'};
            app.ThicknessLabel_13.Text = 'Curve Control 2:';
            app.Panel_17 = uipanel(app.HomogenizationTab);
            app.NumberofGridpointsSpinnerLabel_5 = uilabel(app.Panel_17);
            app.NumberofGridpointsSpinnerLabel_5.Tooltip = {'Specify the number of gridpoints for generating the plot'};
            app.NumberofGridpointsSpinnerLabel_5.Text = 'Number of Gridpoints:';
            app.Gridpoints_2 = uispinner(app.Panel_17);
            app.Gridpoints_2.Limits = [0 Inf];
            app.Gridpoints_2.RoundFractionalValues = 'on';
            app.Gridpoints_2.Value = 30;
            app.CurvepointsLabel_2 = uilabel(app.Panel_17);
            app.CurvepointsLabel_2.Tooltip = {'Specify the number of line segments for defining the curvature'};
            app.CurvepointsLabel_2.Text = 'Number of Curvepoints:';
            app.Curvepoints_2 = uispinner(app.Panel_17);
            app.Curvepoints_2.Enable = 'off';
            app.Curvepoints_2.Value = 20;
            app.ResolutionControlLabel_2 = uilabel(app.Panel_17);
            app.ResolutionControlLabel_2.Text = 'Resolution Control';
            app.Panel_19 = uipanel(app.HomogenizationTab);
            app.MaterialLabel = uilabel(app.Panel_19);
            app.MaterialLabel.Text = 'Material';
            app.ModulusLabel = uilabel(app.Panel_19);
            app.ModulusLabel.Text = 'Modulus (Pa):';
            app.Modulus = uispinner(app.Panel_19);
            app.Modulus.Limits = [0 Inf];
            app.Modulus.Value = 1000000000;
            app.PoissonLabel = uilabel(app.Panel_19);
            app.PoissonLabel.Text = 'Poisson:';
            app.Poisson = uispinner(app.Panel_19);
            app.Poisson.Limits = [0 1];
            app.Poisson.Value = 0.3;
            app.Panel_20 = uipanel(app.HomogenizationTab);
            app.SM3 = uieditfield(app.Panel_20, 'text');
            app.SM3.Editable = 'off';
            app.SM3.Tooltip = {''};
            app.SM2 = uieditfield(app.Panel_20, 'text');
            app.SM2.Editable = 'off';
            app.SM2.Tooltip = {''};
            app.SM1 = uieditfield(app.Panel_20, 'text');
            app.SM1.Editable = 'off';
            app.SM1.Tooltip = {''};
            app.ExactRelativeDensityLabel_8 = uilabel(app.Panel_20);
            app.ExactRelativeDensityLabel_8.Tooltip = {'The number of nodes in the 3D grid is displayed here'};
            app.ExactRelativeDensityLabel_8.Text = 'Shear Modulus:';
            app.YM3 = uieditfield(app.Panel_20, 'text');
            app.YM3.Editable = 'off';
            app.YM3.Tooltip = {''};
            app.YM2 = uieditfield(app.Panel_20, 'text');
            app.YM2.Editable = 'off';
            app.YM2.Tooltip = {''};
            app.YM1 = uieditfield(app.Panel_20, 'text');
            app.YM1.Editable = 'off';
            app.YM1.Tooltip = {''};
            app.YoungsModulusLabel = uilabel(app.Panel_20);
            app.YoungsModulusLabel.Tooltip = {'The number of nodes in the 3D grid is displayed here'};
            app.YoungsModulusLabel.Text = 'Young''s Modulus:';
            app.ResultsLabel = uilabel(app.Panel_20);
            app.ResultsLabel.Tooltip = {''};
            app.ResultsLabel.Text = 'Results';
            app.PR6 = uieditfield(app.Panel_20, 'text');
            app.PR6.Editable = 'off';
            app.PR6.Tooltip = {''};
            app.PR5 = uieditfield(app.Panel_20, 'text');
            app.PR5.Editable = 'off';
            app.PR5.Tooltip = {''};
            app.PR4 = uieditfield(app.Panel_20, 'text');
            app.PR4.Editable = 'off';
            app.PR4.Tooltip = {''};
            app.PR3 = uieditfield(app.Panel_20, 'text');
            app.PR3.Editable = 'off';
            app.PR3.Tooltip = {''};
            app.PR2 = uieditfield(app.Panel_20, 'text');
            app.PR2.Editable = 'off';
            app.PR2.Tooltip = {''};
            app.PR1 = uieditfield(app.Panel_20, 'text');
            app.PR1.Editable = 'off';
            app.PR1.Tooltip = {''};
            app.PoissonsRatioLabel = uilabel(app.Panel_20);
            app.PoissonsRatioLabel.Tooltip = {'The exact relative density of the structure is displayed here'};
            app.PoissonsRatioLabel.Text = 'Poisson''s Ratio:';
            app.ALabel_9 = uilabel(app.Panel_20);
            app.ALabel_9.Tooltip = {''};
            app.ALabel_9.Text = 'Ex';
            app.ALabel_10 = uilabel(app.Panel_20);
            app.ALabel_10.Tooltip = {''};
            app.ALabel_10.Text = 'Ey';
            app.ALabel_11 = uilabel(app.Panel_20);
            app.ALabel_11.Tooltip = {''};
            app.ALabel_11.Text = 'Ez';
            app.ALabel_12 = uilabel(app.Panel_20);
            app.ALabel_12.Tooltip = {''};
            app.ALabel_12.Text = 'Gyz';
            app.ALabel_13 = uilabel(app.Panel_20);
            app.ALabel_13.Tooltip = {''};
            app.ALabel_13.Text = 'Gzx';
            app.ALabel_14 = uilabel(app.Panel_20);
            app.ALabel_14.Tooltip = {''};
            app.ALabel_14.Text = 'Gxy';
            app.ALabel_15 = uilabel(app.Panel_20);
            app.ALabel_15.Tooltip = {''};
            app.ALabel_15.Text = 'Vxy';
            app.ALabel_16 = uilabel(app.Panel_20);
            app.ALabel_16.Tooltip = {''};
            app.ALabel_16.Text = 'Vyx';
            app.ALabel_17 = uilabel(app.Panel_20);
            app.ALabel_17.Tooltip = {''};
            app.ALabel_17.Text = 'Vxz';
            app.ALabel_18 = uilabel(app.Panel_20);
            app.ALabel_18.Tooltip = {''};
            app.ALabel_18.Text = 'Vzx';
            app.ALabel_19 = uilabel(app.Panel_20);
            app.ALabel_19.Tooltip = {''};
            app.ALabel_19.Text = 'Vyz';
            app.ALabel_20 = uilabel(app.Panel_20);
            app.ALabel_20.Tooltip = {''};
            app.ALabel_20.Text = 'Vzy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StrutGen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end